classdef TsiT_CovApp_vol3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ComputationtimeLabel            matlab.ui.control.Label
        GridLayout25                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SYNTHETICFIELDGENERATIONTab     matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        PlottingPanel                   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        PlottypeDropDown                matlab.ui.control.DropDown
        PlottypeDropDownLabel           matlab.ui.control.Label
        CreatePlotButton                matlab.ui.control.Button
        ClearPlotButton                 matlab.ui.control.Button
        GridLayout27                    matlab.ui.container.GridLayout
        ResetButton                     matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        CreateFieldButton               matlab.ui.control.Button
        FIELDSTATUSPanel                matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        Lamp                            matlab.ui.control.Lamp
        LongituderangeLabel             matlab.ui.control.Label
        LatituderangeLabel              matlab.ui.control.Label
        PointsLabel                     matlab.ui.control.Label
        StatusnotgeneratedLabel         matlab.ui.control.Label
        GENERATIONSETTINGSPanel         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        mGalLabel                       matlab.ui.control.Label
        cellsLabel                      matlab.ui.control.Label
        cellsLabel_2                    matlab.ui.control.Label
        SignalStdEditField              matlab.ui.control.NumericEditField
        SignalStdEditFieldLabel         matlab.ui.control.Label
        SmoothingScaleEditField         matlab.ui.control.NumericEditField
        SmoothingScaleEditFieldLabel    matlab.ui.control.Label
        GridResolutionEditField         matlab.ui.control.NumericEditField
        GridResolutionEditFieldLabel    matlab.ui.control.Label
        UseSuggestedSettingsCheckBox    matlab.ui.control.CheckBox
        FIELDPARAMETERSPanel            matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        degreesLabel_4                  matlab.ui.control.Label
        degreesLabel_3                  matlab.ui.control.Label
        degreesLabel_2                  matlab.ui.control.Label
        degreesLabel                    matlab.ui.control.Label
        NumberofPointsEditField         matlab.ui.control.NumericEditField
        NumberofPointsEditFieldLabel    matlab.ui.control.Label
        LongitudeMaxEditField           matlab.ui.control.NumericEditField
        LongitudeMaxEditFieldLabel      matlab.ui.control.Label
        LongitudeMinEditField           matlab.ui.control.NumericEditField
        LongitudeMinEditFieldLabel      matlab.ui.control.Label
        LatitudeMaxEditField            matlab.ui.control.NumericEditField
        LatitudeMaxEditFieldLabel       matlab.ui.control.Label
        LatitudeMinEditField            matlab.ui.control.NumericEditField
        LatitudeMinEditFieldLabel       matlab.ui.control.Label
        RandomGenerationSeedSpinner     matlab.ui.control.Spinner
        RandomGenerationSeedSpinnerLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ANALYTICALMODELFITTINGTab       matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        Image_2                         matlab.ui.control.Image
        FITTINGPanel                    matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        ModelfittingTab                 matlab.ui.container.Tab
        GridLayout29                    matlab.ui.container.GridLayout
        ClearButton                     matlab.ui.control.Button
        ClearDataButton                 matlab.ui.control.Button
        FitButton                       matlab.ui.control.Button
        AnalyticalModelDropDown         matlab.ui.control.DropDown
        AnalyticalModelDropDownLabel    matlab.ui.control.Label
        FittedSSELabel                  matlab.ui.control.Label
        Correlationlength12Label_2      matlab.ui.control.Label
        ModelVarianceC0Label            matlab.ui.control.Label
        ModelnameLabel                  matlab.ui.control.Label
        ComparisonTab                   matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        EMPIRIRCALCOVARIANCEPanel       matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        NmaxEditField                   matlab.ui.control.NumericEditField
        MaximumdegreeofexpansionEditFieldLabel  matlab.ui.control.Label
        DefinemaxdistancelimitCheckBox  matlab.ui.control.CheckBox
        DistanceTypeDropDown            matlab.ui.control.DropDown
        DistanceTypeDropDownLabel       matlab.ui.control.Label
        DatatypeDropDown                matlab.ui.control.DropDown
        DatatypeDropDownLabel           matlab.ui.control.Label
        FIELDSTATUSPanel_2              matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        ResetButton_3                   matlab.ui.control.Button
        ComputationstateLabel           matlab.ui.control.Label
        DatastdavailableLabel           matlab.ui.control.Label
        RegiongridLabel                 matlab.ui.control.Label
        DistancetypeLabel               matlab.ui.control.Label
        NumberofPointsLabel             matlab.ui.control.Label
        REASULTSSTATUSPanel             matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        Correlationlength12Label        matlab.ui.control.Label
        MaxdistanceLabel                matlab.ui.control.Label
        MindistanceLabel                matlab.ui.control.Label
        NumberofbinsLabel               matlab.ui.control.Label
        EmpiricalVarianceC0Label        matlab.ui.control.Label
        DistancelimitEditField          matlab.ui.control.NumericEditField
        DistancelimitEditFieldLabel     matlab.ui.control.Label
        ComputeButton                   matlab.ui.control.Button
        ImportDataButton                matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        POINTPREDICTIONTab              matlab.ui.container.Tab
        GridLayout30                    matlab.ui.container.GridLayout
        Image_3                         matlab.ui.control.Image
        RESULTSSTATUSPanel              matlab.ui.container.Panel
        GridLayout22_8                  matlab.ui.container.GridLayout
        NumericalstatusLabel            matlab.ui.control.Label
        MatrixsizeLabel                 matlab.ui.control.Label
        DatasplitpointsLabel            matlab.ui.control.Label
        StageLabel                      matlab.ui.control.Label
        ModeLabel                       matlab.ui.control.Label
        MinMaxpredictionstdLabel        matlab.ui.control.Label
        MeanpredictionstdLabel          matlab.ui.control.Label
        StdresidualLabel                matlab.ui.control.Label
        BiasLabel                       matlab.ui.control.Label
        RMSELabel                       matlab.ui.control.Label
        ComputationtimeLabel_2          matlab.ui.control.Label
        TargetquantityLabel             matlab.ui.control.Label
        ModelLabel_4                    matlab.ui.control.Label
        ObservationsusedLabel           matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        StatusidleLabel                 matlab.ui.control.Label
        ResetButton_4                   matlab.ui.control.Button
        PlottingPanel_2                 matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        TabGroup3_2                     matlab.ui.container.TabGroup
        InputFieldTab                   matlab.ui.container.Tab
        GridLayout24                    matlab.ui.container.GridLayout
        ClearplotButton                 matlab.ui.control.Button
        ExportPlotButton                matlab.ui.control.Button
        CreatePlotButton_2              matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        PredictionMapTab                matlab.ui.container.Tab
        GridLayout24_2                  matlab.ui.container.GridLayout
        ClearplotButton_2               matlab.ui.control.Button
        ExportPlotButton_2              matlab.ui.control.Button
        CreatePlotButton_3              matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        ResidualMapTab                  matlab.ui.container.Tab
        GridLayout24_3                  matlab.ui.container.GridLayout
        ClearplotButton_3               matlab.ui.control.Button
        ExportPlotButton_3              matlab.ui.control.Button
        CreatePlotButton_4              matlab.ui.control.Button
        UIAxes5                         matlab.ui.control.UIAxes
        PredictionAccuracyTab           matlab.ui.container.Tab
        GridLayout24_7                  matlab.ui.container.GridLayout
        ClearplotButton_7               matlab.ui.control.Button
        ExportPlotButton_7              matlab.ui.control.Button
        CreatePlotButton_8              matlab.ui.control.Button
        UIAxes5_3                       matlab.ui.control.UIAxes
        ErrorCovarianceMatrixTab        matlab.ui.container.Tab
        GridLayout24_6                  matlab.ui.container.GridLayout
        ClearplotButton_6               matlab.ui.control.Button
        ExportPlotButton_6              matlab.ui.control.Button
        CreatePlotButton_7              matlab.ui.control.Button
        UIAxes5_2                       matlab.ui.control.UIAxes
        ResidualHistogramTab            matlab.ui.container.Tab
        GridLayout24_4                  matlab.ui.container.GridLayout
        ClearplotButton_4               matlab.ui.control.Button
        ExportPlotButton_4              matlab.ui.control.Button
        CreatePlotButton_5              matlab.ui.control.Button
        UIAxes6                         matlab.ui.control.UIAxes
        ResidualGeoidFieldTab           matlab.ui.container.Tab
        GridLayout24_5                  matlab.ui.container.GridLayout
        ClearplotButton_5               matlab.ui.control.Button
        ExportPlotButton_5              matlab.ui.control.Button
        CreatePlotButton_6              matlab.ui.control.Button
        UIAxes7                         matlab.ui.control.UIAxes
        REALDATAPREDICTIONPanel         matlab.ui.container.Panel
        GridLayout31                    matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        PredictionFileTab               matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        RunButton_4                     matlab.ui.control.Button
        ImportPredictionFileButton      matlab.ui.control.Button
        PredictionpointsLabel           matlab.ui.control.Label
        FormatLabel                     matlab.ui.control.Label
        PredictionfilenotloadedLabel    matlab.ui.control.Label
        TrainFieldTab                   matlab.ui.container.Tab
        GridLayout33                    matlab.ui.container.GridLayout
        RunButton_6                     matlab.ui.control.Button
        RandomSeedSpinner_3             matlab.ui.control.Spinner
        RandomSeedSpinner_3Label        matlab.ui.control.Label
        EditdefaultvalueCheckBox_2      matlab.ui.control.CheckBox
        TestsplitEditField_2            matlab.ui.control.NumericEditField
        TestsplitLabel_2                matlab.ui.control.Label
        SYNTHETICVALIDATIONPanel        matlab.ui.container.Panel
        GridLayout22_6                  matlab.ui.container.GridLayout
        TestsplitLabel                  matlab.ui.control.Label
        RandomSeedSpinner_2             matlab.ui.control.Spinner
        RandomSeedSpinner_2Label        matlab.ui.control.Label
        EditdefaultvalueCheckBox        matlab.ui.control.CheckBox
        TestsplitEditField              matlab.ui.control.NumericEditField
        RunButton_5                     matlab.ui.control.Button
        LSCSETTINGSPanel                matlab.ui.container.Panel
        GridLayout22_7                  matlab.ui.container.GridLayout
        EditdefaultstdCheckBox          matlab.ui.control.CheckBox
        ObsarvationnoiseEditField       matlab.ui.control.NumericEditField
        ObsarvationnoiseEditFieldLabel  matlab.ui.control.Label
        DistancetypeLabel_2             matlab.ui.control.Label
        ModelLabel_3                    matlab.ui.control.Label
        PredictiontypeDropDown          matlab.ui.control.DropDown
        PredictiontypeDropDownLabel     matlab.ui.control.Label
    end

    % ============================================================
    % Author: Tsiridis Theodoros
    % Year: 2026
    %
    % Diploma Thesis:
    % Covariance Models in Point Adjustment Applications
    % for the Analysis of Gravity Field Components
    %
    % Aristotle University of Thessaloniki (AUTH)
    % Department of Surveying and Geodesy Engineering
    %
    % ------------------------------------------------------------
    % Copyright (c) 2026 Tsiridis T.
    % All rights reserved.
    %
    % This code is provided for academic purposes only.
    % ============================================================

    properties (Access = private)
        GeneratedData
        GeoAxes
        EmpiricalResults
        ImportedData
        FitResults
        ComparisonTableData = {}

        PredictionInputData
        PredictionResults
        PredictionResiduals
        PredictionStd
        PredictionErrorCov
        SyntheticTrainData
        SyntheticCheckData

        PredictionMode = ""
        PredictionInputFile = ""
        PredictionReady = false

        PredictionTruth
        PredictionErrorTable = table()

        GeoidGainG = 0.002
        GeoidCrossCorrelation = 0.90
        GeoidMeanOffset = 0
    end

    methods (Access = private)

        function ok = validateInputs(app)
            ok = true;

            n_points    = app.NumberofPointsEditField.Value;
            seed        = app.RandomGenerationSeedSpinner.Value;
            lat_min     = app.LatitudeMinEditField.Value;
            lat_max     = app.LatitudeMaxEditField.Value;
            lon_min     = app.LongitudeMinEditField.Value;
            lon_max     = app.LongitudeMaxEditField.Value;
            grid_dim    = app.GridResolutionEditField.Value;
            sigma_cells = app.SmoothingScaleEditField.Value;
            signal_std  = app.SignalStdEditField.Value;

            % Number of points
            if n_points <= 0 || mod(n_points,1) ~= 0
                uialert(app.UIFigure, ...
                    'Number of Points must be a positive integer.', ...
                    'Invalid Input');
                ok = false;
                return;
            end

            % Seed
            if seed < 0 || mod(seed,1) ~= 0
                uialert(app.UIFigure, ...
                    'Random Seed must be a non-negative integer.', ...
                    'Invalid Input');
                ok = false;
                return;
            end

            % Latitude limits
            if lat_min < -90 || lat_min > 90 || lat_max < -90 || lat_max > 90
                uialert(app.UIFigure, ...
                    'Latitude values must be in the range [-90, 90] degrees.', ...
                    'Invalid Input');
                ok = false;
                return;
            end

            if lat_min >= lat_max
                uialert(app.UIFigure, ...
                    'Latitude Min must be smaller than Latitude Max.', ...
                    'Invalid Input');
                ok = false;
                return;
            end

            % Longitude limits
            if lon_min < -180 || lon_min > 180 || lon_max < -180 || lon_max > 180
                uialert(app.UIFigure, ...
                    'Longitude values must be in the range [-180, 180] degrees.', ...
                    'Invalid Input');
                ok = false;
                return;
            end

            if lon_min >= lon_max
                uialert(app.UIFigure, ...
                    'Longitude Min must be smaller than Longitude Max.', ...
                    'Invalid Input');
                ok = false;
                return;
            end

            % Grid size
            if grid_dim <= 0 || mod(grid_dim,1) ~= 0
                uialert(app.UIFigure, ...
                    'Grid Size must be a positive integer.', ...
                    'Invalid Input');
                ok = false;
                return;
            end

            % Sigma cells
            if sigma_cells <= 0 || sigma_cells > 100
                uialert(app.UIFigure, ...
                    'Sigma Cells must be greater than 0 and reasonably small (e.g. <= 100).', ...
                    'Invalid Input');
                ok = false;
                return;
            end

            % Signal std
            if signal_std <= 0
                uialert(app.UIFigure, ...
                    'Variance Scale must be positive.', ...
                    'Invalid Input');
                ok = false;
                return;
            end

            if sigma_cells >= grid_dim/2
                uialert(app.UIFigure, ...
                    'Sigma Cells is too large compared to Grid Size.', ...
                    'Invalid Input');
                ok = false;
                return;
            end

        end

        function initialStatus(app)
            app.StatusnotgeneratedLabel.Text = 'Status: not generated';
            app.Lamp.Color                         = 'red';
            app.PointsLabel.Text             = 'Points: -';
            app.LatituderangeLabel.Text           = 'Latitude range: -';
            app.LongituderangeLabel.Text         = 'Longitude range: -';
        end

        function StatusGenerated(app, n_points, lat_min, lat_max, lon_min, lon_max)
            app.StatusnotgeneratedLabel.Text = 'Status: generated successfully';
            app.Lamp.Color = 'green';
            app.PointsLabel.Text = sprintf('Points: %d', n_points);
            app.LatituderangeLabel.Text = sprintf('Latitude range: [%.2f, %.2f]', lat_min, lat_max);
            app.LongituderangeLabel.Text = sprintf('Longitude range: [%.2f, %.2f]', lon_min, lon_max);
        end

        function plotEmpiricalCovarianceOnAxes(app, results)
            ax = app.UIAxes2;

            lags_all   = results.lags;
            C_all      = results.C_emp;

            if isfield(results, 'lags_valid_with_zero')
                lags_main = results.lags_valid_with_zero;
                C_main    = results.C_emp_valid_with_zero;
            else
                lags_main = [0; results.lags_valid(:)];
                C_main    = [results.C0_emp; results.C_emp_valid(:)];
            end

            lags_valid = results.lags_valid;
            C_valid    = results.C_emp_valid;

            C0_emp     = results.C0_emp;
            Lhalf_emp  = results.Lhalf_emp;
            min_pairs  = results.min_pairs_bin;

            cla(ax);
            hold(ax, 'on');
            grid(ax, 'on');
            box(ax, 'on');
            mainColor = [0 64 115] / 255;

            % All bins shown only as diagnostic points
            h0 = plot(ax, lags_all, C_all, '.', ...
                'MarkerSize', 7, ...
                'Color',  mainColor);

            % Main empirical covariance: C(0) + valid bins
            h1 = plot(ax, lags_main, C_main, '-o', ...
                'LineWidth', 1.2, ...
                'MarkerSize', 4, ...
                'Color',  mainColor);

            % Valid bins highlighted
            h2 = plot(ax, lags_valid, C_valid, 'o', ...
                'LineWidth', 1.5, ...
                'MarkerSize', 5, ...
                'Color',  mainColor);

            target_emp = 0.5 * C0_emp;
            h3 = yline(ax, target_emp, '--', '$0.5\,C(0)$', ...
                'Interpreter','latex', ...
                'LabelHorizontalAlignment','left');

            xlabel(ax, 'Lag distance $h$ (km)', 'Interpreter','latex');
            ylabel(ax, 'Empirical covariance $C(h)$ (mGal$^2$)', 'Interpreter','latex');
            title(ax, 'Empirical covariance function', 'Interpreter','latex');

            if isfinite(Lhalf_emp)
                h4 = xline(ax, Lhalf_emp, '--', ...
                    sprintf('$\\xi_{1/2}^{(emp)} \\approx %.2f\\ \\mathrm{km}$', Lhalf_emp), ...
                    'Interpreter','latex', ...
                    'LabelOrientation','horizontal', ...
                    'LabelVerticalAlignment','bottom', ...
                    'LabelHorizontalAlignment','left');

                legend(ax, [h0 h1 h2 h3 h4], {
                    'All bins',...
                    'Empirical covariance: $C(0)$ + valid bins',...
                    sprintf('Valid bins ($N_{pairs} \\geq %d$)', min_pairs),...
                    '$0.5\,C(0)$',...
                    '$\xi_{1/2}^{(emp)}$'}, ...
                    'Interpreter','latex', ...
                    'Location','northeast');
            else
                legend(ax, [h0 h1 h2 h3], {
                    'All bins',...
                    'Empirical covariance: $C(0)$ + valid bins',...
                    sprintf('Valid bins ($N_{pairs} \\geq %d$)', min_pairs),...
                    '$0.5\,C(0)$'}, ...
                    'Interpreter','latex', ...
                    'Location','northeast');
            end

            xl = xlim(ax);
            yl = ylim(ax);

            if isfinite(Lhalf_emp)
                txt = { ...
                    sprintf('Empirical $C(0)$: %.2f $\\mathrm{mGal}^2$', C0_emp), ...
                    sprintf('Empirical $\\xi_{1/2}$: %.2f km', Lhalf_emp), ...
                    sprintf('Minimum pairs/bin: %d', min_pairs)};
            else
                txt = { ...
                    sprintf('Empirical $C(0)$: %.2f $\\mathrm{mGal}^2$', C0_emp), ...
                    'Empirical $\xi_{1/2}$: not available', ...
                    sprintf('Minimum pairs/bin: %d', min_pairs)};
            end

            text(ax, ...
                xl(2) - 0.03*(xl(2)-xl(1)), ...
                yl(1) + 0.10*(yl(2)-yl(1)), ...
                txt, ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'Interpreter','latex', ...
                'BackgroundColor','w', ...
                'EdgeColor',[0.35 0.35 0.35], ...
                'Margin',6, ...
                'FontSize',10);

            hold(ax, 'off');

        end

        function plotEmpiricalVsAnalyticalOnAxes(app, results, fit)

            ax = app.UIAxes2;

            % Extract empirical data
            lags_all   = results.lags;
            C_all      = results.C_emp;
            lags_valid = results.lags_valid;
            C_valid    = results.C_emp_valid;
            C0_emp     = results.C0_emp;
            Lhalf_emp  = results.Lhalf_emp;
            min_pairs  = results.min_pairs_bin;

            if isfield(results, 'lags_valid_with_zero')
                lags_main = results.lags_valid_with_zero;
                C_main    = results.C_emp_valid_with_zero;
            else
                lags_main = [0; results.lags_valid(:)];
                C_main    = [results.C0_emp; results.C_emp_valid(:)];
            end

            % Dense grid for smooth model curve
            h_plot = linspace(0, max(lags_valid, [], 'omitnan'), 500).';
            C_plot = fit.model_fun(h_plot);

            cla(ax);
            hold(ax, 'on');
            grid(ax, 'on');
            box(ax, 'on');
            mainColor = [0 64 115] / 255;
            FitLineColor = [107 113 111]/255;

            % Empirical covariance (all bins)
            h0 = plot(ax, lags_all, C_all, '.', ...
                'MarkerSize', 7, ...
                'Color', mainColor);

            h1 = plot(ax, lags_main, C_main, '-o', ...
                'LineWidth', 1.2, ...
                'MarkerSize', 4, ...
                'Color', mainColor);

            % Valid bins
            h2 = plot(ax, lags_valid, C_valid, 'o', ...
                'LineWidth', 1.5, ...
                'MarkerSize', 5, ...
                'Color', mainColor);

            % Analytical fitted model
            h3 = plot(ax, h_plot, C_plot, 'LineWidth', 2.0, 'Color',FitLineColor);

            % Half-covariance level for empirical
            target_emp = 0.5 * C0_emp;
            h4 = yline(ax, target_emp, '--', '$0.5\,C(0)$', ...
                'Interpreter','latex', ...
                'LabelHorizontalAlignment','left');

            % Optional: model half-covariance line
            if isfield(fit, 'sigma2')
                target_mod = 0.5 * fit.sigma2;
                h5 = yline(ax, target_mod, ':', 'Model $0.5\,C(0)$', ...
                    'Interpreter','latex', ...
                    'LabelHorizontalAlignment','right');
            else
                h5 = [];
            end

            % Empirical half-distance
            if isfinite(Lhalf_emp)
                h6 = xline(ax, Lhalf_emp, '--', ...
                    sprintf('$\\xi_{1/2}^{(emp)} \\approx %.2f\\ \\mathrm{km}$', Lhalf_emp), ...
                    'Interpreter','latex', ...
                    'LabelOrientation','horizontal', ...
                    'LabelVerticalAlignment','bottom', ...
                    'LabelHorizontalAlignment','left');
            else
                h6 = [];
            end

            % Model half-distance
            if isfield(fit, 'Lhalf_mod') && isfinite(fit.Lhalf_mod)
                h7 = xline(ax, fit.Lhalf_mod, ':', ...
                    sprintf('$\\xi_{1/2}^{(mod)} \\approx %.2f\\ \\mathrm{km}$', fit.Lhalf_mod), ...
                    'Interpreter','latex', ...
                    'LabelOrientation','horizontal', ...
                    'LabelVerticalAlignment','top', ...
                    'LabelHorizontalAlignment','right');
            else
                h7 = [];
            end

            xlabel(ax, 'Lag distance $h$ (km)', 'Interpreter','latex');
            ylabel(ax, 'Covariance $C(h)$ (mGal$^2$)', 'Interpreter','latex');
            title(ax, ['Empirical vs ', fit.model_name, ' covariance model'], 'Interpreter','latex');

            % Legend
            legend_handles = [h0 h1 h2 h3 h4];
            legend_labels = { ...
                'Empirical covariance', ...
                sprintf('Valid bins ($N_{pairs} \\geq %d$)', min_pairs), ...
                [fit.model_name, ' fit'], ...
                '$0.5\,C(0)$'};

            if ~isempty(h5)
                legend_handles = [legend_handles h5];
                legend_labels{end+1} = 'Model $0.5\,C(0)$';
            end
            if ~isempty(h6)
                legend_handles = [legend_handles h6];
                legend_labels{end+1} = '$\xi_{1/2}^{(emp)}$';
            end
            if ~isempty(h7)
                legend_handles = [legend_handles h7];
                legend_labels{end+1} = '$\xi_{1/2}^{(mod)}$';
            end

            legend(ax, legend_handles, legend_labels, ...
                'Interpreter','latex', ...
                'Location','northeast');

            % Annotation box
            xl = xlim(ax);
            yl = ylim(ax);

            if isfield(fit, 'modelID') && strcmpi(fit.modelID, 'tscherningrapp')
                scaleText = sprintf('Spectral $s$: %.5f, cutoff $N$: %d, $n_{max}$: %d', ...
                    fit.s, fit.N, fit.nmax);
            elseif isfield(fit, 'modelID') && strcmpi(fit.modelID, 'cauchy') && isfield(fit, 'alpha')
                scaleText = sprintf('Scale $a$: %.2f km, $\\alpha$: %.1f', fit.a, fit.alpha);

            elseif isfield(fit, 'a') && isfinite(fit.a)
                scaleText = sprintf('Model scale parameter: %.2f km', fit.a);

            elseif isfield(fit, 's') && isfinite(fit.s)
                scaleText = sprintf('Spectral parameter $s$: %.5f', fit.s);

            else
                scaleText = 'Model scale parameter: not available';
            end

            txt = { ...
                sprintf('Empirical $C(0)$: %.2f $\\mathrm{mGal}^2$', C0_emp), ...
                sprintf('Model $\\sigma^2$: %.2f $\\mathrm{mGal}^2$', fit.sigma2), ...
                scaleText, ...
                sprintf('Fit WRMSE: %.4f $\\mathrm{mGal}^2$', fit.wrmse_cov)};

            if isfinite(Lhalf_emp)
                txt{end+1} = sprintf('Empirical $\\xi_{1/2}$: %.2f km', Lhalf_emp);
            end
            if isfield(fit, 'Lhalf_mod') && isfinite(fit.Lhalf_mod)
                txt{end+1} = sprintf('Model $\\xi_{1/2}$: %.2f km', fit.Lhalf_mod);
            end

            x_mid = mean(xl);
            y_top = yl(1) + 0.85*(yl(2)-yl(1));

            text(ax, x_mid, y_top, txt, ...
                'HorizontalAlignment','left', ...
                'VerticalAlignment','top', ...
                'Interpreter','latex', ...
                'BackgroundColor','w');

            hold(ax, 'off');
        end

        function appendFitToComparisonTable(app, fit)

            newRow = { ...
                fit.model_name, ...
                sprintf('%.4f', fit.sigma2), ...
                sprintf('%.4f', fit.Lhalf_mod), ...
                sprintf('%.4f', fit.wrmse_cov)};

            currentData = app.UITable.Data;

            if isempty(currentData)
                app.UITable.Data = newRow;
            else
                app.UITable.Data = [currentData; newRow];
            end
        end

        function updateAnalyticalModelOptions(app)
            sphericalModels = { ...
                'Exponential', ...
                'Hirvonen', ...
                'Gaussian', ...
                '2nd-order Gauss-Markov', ...
                '3rd-order Gauss-Markov', ...
                'Tscherning-Rapp', ...
                'Spherical', ...
                'Cauchy'};

            switch app.DistanceTypeDropDown.Value

                case 'Euclidean'

                    % Για ευκλείδιες/επίπεδες αποστάσεις επιτρέπεται μόνο το Forsberg
                    app.AnalyticalModelDropDown.Items = {'Forsberg Planar'};
                    app.AnalyticalModelDropDown.Value = 'Forsberg Planar';

                case 'Spherical'

                    % Για σφαιρικές αποστάσεις εμφανίζονται όλα τα υπόλοιπα μοντέλα
                    app.AnalyticalModelDropDown.Items = sphericalModels;

                    if strcmp(app.AnalyticalModelDropDown.Value, 'Forsberg Planar') || ...
                            ~any(strcmp(app.AnalyticalModelDropDown.Value, sphericalModels))
                        app.AnalyticalModelDropDown.Value = 'Exponential';
                    end
            end

        end

        function initialStatusEmpirical(app)
            app.NumberofPointsLabel.Text = 'Number of Points: -';
            app.DistancetypeLabel.Text   = 'Distance type: -';
            app.RegiongridLabel.Text = 'Region grid: -';
            app.DatastdavailableLabel.Text = 'Data std: available';


            app.MaxdistanceLabel.Text = 'Max distance: -';
            app.NumberofbinsLabel.Text = 'Number of bins: -';
            app.EmpiricalVarianceC0Label.Text = 'Empirical Variance C(0): -';


            app.Correlationlength12Label.Text = 'Correlation length xi1/2: -';


            app.ComputationstateLabel.Visible = "off";
            app.ComputationstateLabel.Text = '';
        end

        function initialStatusPrediction(app)
            app.StatusidleLabel.Text = 'Status: idle';
            app.Lamp_2.Color = 'red';

            app.ObservationsusedLabel.Text = 'Observations used: -';
            app.ModelLabel_4.Text = 'Model: -';
            app.TargetquantityLabel.Text = 'Target quantity: -';
            app.ComputationtimeLabel_2.Text = 'Computation time: -';

            app.RMSELabel.Text = 'RMSE: -';
            app.BiasLabel.Text = 'Bias: -';
            app.StdresidualLabel.Text = 'Std residual: -';
            app.MeanpredictionstdLabel.Text = 'Mean prediction std: -';
            app.MinMaxpredictionstdLabel.Text = 'Min/Max prediction std: -';
            app.ModeLabel.Text = 'Mode: -';
            app.StageLabel.Text = 'Stage: -';
            app.DatasplitpointsLabel.Text = 'Data split / points: -';
            app.MatrixsizeLabel.Text = 'Matrix size: -';
            app.NumericalstatusLabel.Text = 'Numerical status: -';

            app.PredictionfilenotloadedLabel.Text = 'Prediction file: not loaded';
            app.FormatLabel.Text = 'Format: -';
            app.PredictionpointsLabel.Text = 'Prediction points: -';

            app.RMSELabel.Enable = 'off';
            app.BiasLabel.Enable = 'off';
            app.StdresidualLabel.Enable = 'off';
            app.MeanpredictionstdLabel.Enable = 'off';
            app.MinMaxpredictionstdLabel.Enable = 'off';

            app.PredictionInputData = [];
            app.PredictionResults = [];
            app.PredictionResiduals = [];
            app.PredictionStd = [];
            app.PredictionTruth = [];
            app.PredictionErrorTable = table();
            app.PredictionErrorCov = [];

            app.SyntheticTrainData = [];
            app.SyntheticCheckData = [];

            app.PredictionMode = "";
            app.PredictionInputFile = "";
            app.PredictionReady = false;

            cla(app.UIAxes3);   colorbar(app.UIAxes3,'off');
            cla(app.UIAxes4);   colorbar(app.UIAxes4,'off');
            cla(app.UIAxes5);   colorbar(app.UIAxes5,'off');
            cla(app.UIAxes5_3); colorbar(app.UIAxes5_3,'off');
            cla(app.UIAxes5_2); colorbar(app.UIAxes5_2,'off');
            cla(app.UIAxes6);   colorbar(app.UIAxes6,'off');
            cla(app.UIAxes7);   colorbar(app.UIAxes7,'off');

        end

        function updateLSCSettingsFromFit(app)
            if isempty(app.FitResults)
                app.ModelLabel_3.Text = 'Model: -';
                app.DistancetypeLabel_2.Text = 'Distance type: -';
                return;
            end

            app.ModelLabel_3.Text = sprintf('Model: %s', app.FitResults.model_name);
            app.DistancetypeLabel_2.Text = sprintf('Distance type: %s', app.DistanceTypeDropDown.Value);
        end

        function data_obs = getCurrentObservationData(app)
            switch app.DatatypeDropDown.Value

                case 'Synthetic Data'
                    data_obs = app.GeneratedData;

                case 'User Data'
                    data_obs = app.ImportedData;

                otherwise
                    error('Unknown observation data source.');
            end

            if isempty(data_obs)
                error('No observation dataset available.');
            end

            % Ensure that std_grav always exists before LSC
            data_obs = ensureObservationStd(app, data_obs);
        end

        function stylePredictionAxes(app, ax, xlab, ylab, ttl)
            cla(ax);
            colorbar(ax, 'off');
            hold(ax, 'on');
            grid(ax, 'on');
            box(ax, 'on');

            ax.FontName = 'Times New Roman';
            ax.FontSize = 11;
            ax.LineWidth = 1.0;
            ax.TickLabelInterpreter = 'latex';

            xlabel(ax, xlab, ...
                'Interpreter', 'latex', ...
                'FontSize', 12);

            ylabel(ax, ylab, ...
                'Interpreter', 'latex', ...
                'FontSize', 12);

            title(ax, ttl, ...
                'Interpreter', 'latex', ...
                'FontSize', 13, ...
                'FontWeight', 'bold');

            fixPointPredictionAxesLabelClipping(app, ax);
        end

        function finishPredictionAxes(app, ax)
            hold(ax, 'off');
        end

        function plotDataFieldOnAxes(app)
            ax = app.UIAxes3;

            styleMapAxes(app, ax, 'Input gravity field');

            switch app.PredictionMode

                case {'synthetic', 'real_validation'}

                    if isempty(app.SyntheticTrainData) || isempty(app.SyntheticCheckData)
                        error('No validation data available.');
                    end

                    x_obs = app.SyntheticTrainData.lon(:);
                    y_obs = app.SyntheticTrainData.lat(:);
                    z_obs = app.SyntheticTrainData.grav(:);

                    x_pred = app.SyntheticCheckData.lon(:);
                    y_pred = app.SyntheticCheckData.lat(:);

                case 'real'

                    if isempty(app.PredictionInputData)
                        error('No prediction points loaded.');
                    end

                    data_obs = getCurrentObservationData(app);

                    x_obs = data_obs.lon(:);
                    y_obs = data_obs.lat(:);
                    z_obs = data_obs.grav(:);

                    x_pred = app.PredictionInputData.lon(:);
                    y_pred = app.PredictionInputData.lat(:);

                otherwise
                    error('No prediction mode available. Run prediction first.');
            end

            lonLim = [min([x_obs; x_pred]), max([x_obs; x_pred])];
            latLim = [min([y_obs; y_pred]), max([y_obs; y_pred])];

            xlim(ax, lonLim);
            ylim(ax, latLim);

            [Xq, Yq, Zq] = makeInterpolationGrid(app, x_obs, y_obs, z_obs, 160);

            contourf(ax, Xq, Yq, Zq, 35, 'LineStyle', 'none');

            addMapColorbar(app, ax, '$\Delta g$ (mGal)');

            plotMapBoundariesClean(app, ax, lonLim, latLim);

            h1 = scatter(ax, x_obs, y_obs, ...
                16, 'k', 'filled', ...
                'MarkerFaceAlpha', 0.55, ...
                'MarkerEdgeAlpha', 0.55);

            h2 = scatter(ax, x_pred, y_pred, ...
                32, 'o', ...
                'MarkerEdgeColor', 'w', ...
                'LineWidth', 1.10);

            legend(ax, [h1 h2], ...
                {'Observation / training points', 'Prediction / check points'}, ...
                'Interpreter', 'latex', ...
                'Location', 'best');

            txt = { ...
                sprintf('Observations: %d', numel(x_obs)), ...
                sprintf('Prediction/check points: %d', numel(x_pred))};

            xl = xlim(ax);
            yl = ylim(ax);

            text(ax, ...
                xl(1) + 0.02*(xl(2)-xl(1)), ...
                yl(2) - 0.03*(yl(2)-yl(1)), ...
                txt, ...
                'Interpreter', 'latex', ...
                'VerticalAlignment', 'top', ...
                'BackgroundColor', 'w', ...
                'EdgeColor', [0.35 0.35 0.35], ...
                'Margin', 6);

            xlim(ax, lonLim);
            ylim(ax, latLim);
            axis(ax, 'normal');
            fixPointPredictionAxesLabelClipping(app, ax);
            hold(ax, 'off');
        end

        function plotPredictionMapOnAxes(app)
            if ~app.PredictionReady
                error('No prediction results available. Run prediction first.');
            end

            ax = app.UIAxes4;
            predType = app.PredictiontypeDropDown.Value;


            switch app.PredictionMode

                case {'synthetic', 'real_validation'}

                    x = app.SyntheticCheckData.lon(:);
                    y = app.SyntheticCheckData.lat(:);
                    z = app.PredictionResults(:);

                case 'real'

                    x = app.PredictionResults.lon(:);
                    y = app.PredictionResults.lat(:);

                    if strcmp(predType, 'Gravity anomaly -> Gravity anomaly')
                        z = app.PredictionResults.g_hat(:);
                    else
                        z = app.PredictionResults.N_hat(:);
                    end

                otherwise
                    error('Unknown prediction mode.');
            end

            if strcmp(predType, 'Gravity anomaly -> Gravity anomaly')
                ttl = 'Predicted gravity anomaly';
                cblab = '$\Delta \hat{g}$ (mGal)';
            else
                ttl = 'Predicted geoid height';
                cblab = '$\hat{N}$ (m)';
            end

            styleMapAxes(app, ax, ttl);

            lonLim = [min(x), max(x)];
            latLim = [min(y), max(y)];

            xlim(ax, lonLim);
            ylim(ax, latLim);

            [Xq, Yq, Zq] = makeInterpolationGrid(app, x, y, z, 160);

            contourf(ax, Xq, Yq, Zq, 35, 'LineStyle', 'none');

            addMapColorbar(app, ax, cblab);

            plotMapBoundariesClean(app, ax, lonLim, latLim);

            scatter(ax, x, y, ...
                14, 'k', 'filled', ...
                'MarkerFaceAlpha', 0.45, ...
                'MarkerEdgeAlpha', 0.45);

            txt = { ...
                sprintf('Prediction points: %d', numel(z)), ...
                sprintf('Mean: %.3f', mean(z, 'omitnan')), ...
                sprintf('Std: %.3f', std(z, 'omitnan'))};

            xl = xlim(ax);
            yl = ylim(ax);

            text(ax, ...
                xl(1) + 0.02*(xl(2)-xl(1)), ...
                yl(2) - 0.03*(yl(2)-yl(1)), ...
                txt, ...
                'Interpreter', 'latex', ...
                'VerticalAlignment', 'top', ...
                'BackgroundColor', 'w', ...
                'EdgeColor', [0.35 0.35 0.35], ...
                'Margin', 6);

            xlim(ax, lonLim);
            ylim(ax, latLim);
            axis(ax, 'normal');

            fixPointPredictionAxesLabelClipping(app, ax);

            hold(ax, 'off');
        end

        function plotValidationScatterOnAxes(app)
            if ~strcmp(app.PredictionMode, 'synthetic') && ...
                    ~strcmp(app.PredictionMode, 'real_validation')
                error('Validation residual map is only available for train/test validation mode.');
            end

            if isempty(app.PredictionTruth) || isempty(app.PredictionResiduals)
                error('No validation residuals available.');
            end

            predType = app.PredictiontypeDropDown.Value;

            if ~strcmp(predType, 'Gravity anomaly -> Gravity anomaly')
                error('Validation residual map is only available for gravity anomaly prediction.');
            end

            ax = app.UIAxes5;

            styleMapAxes(app, ax, 'Validation residuals');

            x = app.SyntheticCheckData.lon(:);
            y = app.SyntheticCheckData.lat(:);
            e = app.PredictionResiduals(:);

            lonLim = [min(x), max(x)];
            latLim = [min(y), max(y)];

            xlim(ax, lonLim);
            ylim(ax, latLim);

            scatter(ax, x, y, ...
                34, e, ...
                'filled', ...
                'MarkerFaceAlpha', 0.85, ...
                'MarkerEdgeColor', 'k', ...
                'LineWidth', 0.40);

            addMapColorbar(app, ax, '$\hat{g}-g$ (mGal)');

            plotMapBoundariesClean(app, ax, lonLim, latLim);

            rmseVal = sqrt(mean(e.^2, 'omitnan'));
            biasVal = mean(e, 'omitnan');
            stdVal  = std(e, 'omitnan');

            txt = { ...
                sprintf('Check points: %d', numel(e)), ...
                sprintf('$RMSE = %.3f\\ \\mathrm{mGal}$', rmseVal), ...
                sprintf('$Bias = %.3f\\ \\mathrm{mGal}$', biasVal), ...
                sprintf('$\\sigma_e = %.3f\\ \\mathrm{mGal}$', stdVal)};

            xl = xlim(ax);
            yl = ylim(ax);

            text(ax, ...
                xl(1) + 0.02*(xl(2)-xl(1)), ...
                yl(2) - 0.03*(yl(2)-yl(1)), ...
                txt, ...
                'Interpreter', 'latex', ...
                'VerticalAlignment', 'top', ...
                'BackgroundColor', 'w', ...
                'EdgeColor', [0.35 0.35 0.35], ...
                'Margin', 6);

            xlim(ax, lonLim);
            ylim(ax, latLim);
            axis(ax, 'normal');
            fixPointPredictionAxesLabelClipping(app, ax);
            hold(ax, 'off');
        end

        function plotResidualHistogramOnAxes(app)
            if ~strcmp(app.PredictionMode, 'synthetic') && ...
                    ~strcmp(app.PredictionMode, 'real_validation')
                error('Residual histogram is only available for train/test validation mode.');
            end

            if isempty(app.PredictionResiduals)
                error('No validation residuals available.');
            end

            predType = app.PredictiontypeDropDown.Value;

            if ~strcmp(predType, 'Gravity anomaly -> Gravity anomaly')
                error('Residual histogram is only available for gravity anomaly prediction.');
            end

            ax = app.UIAxes6;

            stylePredictionAxes(app, ax, ...
                '$\hat{g}-g$ (mGal)', ...
                'Frequency', ...
                'Residual histogram');

            e = app.PredictionResiduals(:);
            e = e(isfinite(e));

            h = histogram(ax, e, 20, ...
                'FaceAlpha', 0.85, ...
                'EdgeColor', 'k');

            xline(ax, 0, '--k', 'LineWidth', 1.20);

            if numel(e) > 2 && std(e) > 0
                mu = mean(e, 'omitnan');
                sg = std(e, 'omitnan');

                xx = linspace(min(e), max(e), 250);
                binWidth = h.BinWidth;

                yy = numel(e) * binWidth * ...
                    (1/(sg*sqrt(2*pi))) .* exp(-0.5*((xx-mu)./sg).^2);

                plot(ax, xx, yy, 'LineWidth', 1.60);
            end

            rmseVal = sqrt(mean(e.^2, 'omitnan'));
            biasVal = mean(e, 'omitnan');
            stdVal  = std(e, 'omitnan');

            txt = { ...
                sprintf('$RMSE = %.3f\\ \\mathrm{mGal}$', rmseVal), ...
                sprintf('$Bias = %.3f\\ \\mathrm{mGal}$', biasVal), ...
                sprintf('$\\sigma_e = %.3f\\ \\mathrm{mGal}$', stdVal)};

            xl = xlim(ax);
            yl = ylim(ax);

            text(ax, ...
                xl(1) + 0.05*(xl(2)-xl(1)), ...
                yl(2) - 0.08*(yl(2)-yl(1)), ...
                txt, ...
                'Interpreter', 'latex', ...
                'VerticalAlignment', 'top', ...
                'BackgroundColor', 'w', ...
                'EdgeColor', [0.35 0.35 0.35], ...
                'Margin', 6);

            grid(ax, 'on');
            box(ax, 'on');
            fixPointPredictionAxesLabelClipping(app, ax);

            hold(ax, 'off');
        end

        function plotGeoidMapOnAxes(app)
            predType = app.PredictiontypeDropDown.Value;

            if ~strcmp(predType, 'Gravity anomaly -> Geoid height')
                error('Geoid map is only available when prediction type is Gravity anomaly -> Geoid height.');
            end

            if ~app.PredictionReady
                error('No prediction results available. Run prediction first.');
            end

            ax = app.UIAxes7;

            styleMapAxes(app, ax, 'Predicted geoid height');

            switch app.PredictionMode

                case {'synthetic', 'real_validation'}

                    x = app.SyntheticCheckData.lon(:);
                    y = app.SyntheticCheckData.lat(:);
                    z = app.PredictionResults(:);

                case 'real'

                    x = app.PredictionResults.lon(:);
                    y = app.PredictionResults.lat(:);
                    z = app.PredictionResults.N_hat(:);

                otherwise
                    error('Unknown prediction mode.');
            end

            lonLim = [min(x), max(x)];
            latLim = [min(y), max(y)];

            xlim(ax, lonLim);
            ylim(ax, latLim);

            [Xq, Yq, Zq] = makeInterpolationGrid(app, x, y, z, 180);

            contourf(ax, Xq, Yq, Zq, 35, 'LineStyle', 'none');

            [C, hC] = contour(ax, Xq, Yq, Zq, 10, ...
                'LineColor', [0.25 0.25 0.25], ...
                'LineWidth', 0.65);

            clabel(C, hC, ...
                'FontSize', 8, ...
                'Color', [0.20 0.20 0.20], ...
                'LabelSpacing', 350);

            addMapColorbar(app, ax, '$\hat{N}$ (m)');

            plotMapBoundariesForGeoid(app, ax, lonLim, latLim);

            scatter(ax, x, y, ...
                8, 'k', 'filled', ...
                'MarkerFaceAlpha', 0.25, ...
                'MarkerEdgeAlpha', 0.25);

            txt = { ...
                sprintf('Prediction points: %d', numel(z)), ...
                sprintf('Min $\\hat{N}$: %.3f m', min(z, [], 'omitnan')), ...
                sprintf('Max $\\hat{N}$: %.3f m', max(z, [], 'omitnan'))};

            xl = xlim(ax);
            yl = ylim(ax);

            text(ax, ...
                xl(1) + 0.02*(xl(2)-xl(1)), ...
                yl(2) - 0.03*(yl(2)-yl(1)), ...
                txt, ...
                'Interpreter', 'latex', ...
                'VerticalAlignment', 'top', ...
                'BackgroundColor', 'w', ...
                'EdgeColor', [0.35 0.35 0.35], ...
                'Margin', 6);

            xlim(ax, lonLim);
            ylim(ax, latLim);
            axis(ax, 'normal');
            fixPointPredictionAxesLabelClipping(app, ax);

            hold(ax, 'off');
        end

        function exportAxesToFile(app, ax)
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'}, 'Save Plot');
            if isequal(file,0) || isequal(path,0)
                return;
            end

            filename = fullfile(path, file);

            f = figure('Visible', 'off', 'Position', [100 100 1100 700]);

            newAx = copyobj(ax, f);

            set(newAx, 'Units', 'normalized', ...
                'Position', [0.15 0.13 0.62 0.78]);

            try
                ti = newAx.TightInset;
                newAx.LooseInset = max(ti + [0.04 0.03 0.03 0.03], ...
                    [0.12 0.08 0.06 0.06]);
            catch
            end

            try
                newAx.YLabel.Units = 'normalized';
                newAx.YLabel.Position(1) = -0.070;
            catch
            end

            exportgraphics(f, filename, 'Resolution', 300);
            close(f);

            uiconfirm(app.UIFigure, ...
                'Plot exported successfully.', ...
                'Export Complete', ...
                'Options', {'OK'});
        end

        function [Xq, Yq, Zq] = makeInterpolationGrid(app, x, y, z, nGrid)
            % MAKEINTERPOLATIONGRID Create smooth interpolation grid

            if nargin < 5 || isempty(nGrid)
                nGrid = 120;
            end

            x = x(:);
            y = y(:);
            z = z(:);

            xq = linspace(min(x), max(x), nGrid);
            yq = linspace(min(y), max(y), nGrid);
            [Xq, Yq] = meshgrid(xq, yq);

            Zq = griddata(x, y, z, Xq, Yq, 'cubic');

            if all(isnan(Zq(:)))
                Zq = griddata(x, y, z, Xq, Yq, 'natural');
            end
        end

        function styleMapAxes(app, ax, ttl)

            cla(ax);
            colorbar(ax, 'off');
            hold(ax, 'on');

            ax.Box = 'on';
            ax.LineWidth = 1.0;
            ax.FontName = 'Times New Roman';
            ax.FontSize = 11;
            ax.TickLabelInterpreter = 'latex';

            grid(ax, 'on');
            ax.GridAlpha = 0.20;

            xlabel(ax, 'Longitude (deg)', ...
                'Interpreter', 'latex', ...
                'FontSize', 12);

            ylabel(ax, 'Latitude (deg)', ...
                'Interpreter', 'latex', ...
                'FontSize', 12);

            title(ax, ttl, ...
                'Interpreter', 'latex', ...
                'FontWeight', 'bold', ...
                'FontSize', 13);

            colormap(ax, turbo);

            fixPointPredictionAxesLabelClipping(app, ax);
        end

        function cb = addMapColorbar(app, ax, labelText)
            %ADDMAPCOLORBAR Add colorbar with visible units inside UIAxes.

            cb = colorbar(ax, 'eastoutside');

            cb.TickLabelInterpreter = 'latex';
            cb.FontName = 'Times New Roman';
            cb.FontSize = 10;

            cb.Label.String = labelText;
            cb.Label.Interpreter = 'latex';
            cb.Label.FontSize = 11;
            cb.Label.Rotation = 90;

            % Move colorbar label so it is visible but not clipped
            cb.Label.Units = 'normalized';
            cb.Label.Position = [1.65 0.50 0];

            % Also fix left and bottom axis labels
            fixPointPredictionAxesLabels(app, ax);

            drawnow limitrate;
        end

        function plotMapBoundariesClean(app, ax, lonLim, latLim)
            if isdeployed
                mapFolder = fullfile(ctfroot, 'Land boundaries');
            else
                mapFolder = fullfile('C:\Users\teots\Documents\MATLAB\DiplThesis\TT_THESIS\Land boundaries');
            end

            coastFile = fullfile(mapFolder, 'ne_10m_coastline.shp');
            borderFile = fullfile(mapFolder, 'ne_10m_admin_0_boundary_lines_land.shp');

            hold(ax, 'on');

            if isfile(coastFile)
                plotSingleShapefile(app, ax, coastFile, lonLim, latLim, [0 0 0], 1.30);
            else
                uialert(app.UIFigure, ...
                    ['coastline shapefile not found:' newline coastFile], ...
                    'Shapefile Error');
            end

            if isfile(borderFile)
                plotSingleShapefile(app, ax, borderFile, lonLim, latLim, [0 0 0], 0.90);
            else
                warning('Boundary shapefile not found: %s', borderFile);
            end
        end

        function plotSingleShapefile(app, ax, shpFile, lonLim, latLim, lineColor, lineWidth)
            S = shaperead(shpFile);

            if isempty(S)
                warning('Shapefile is empty: %s', shpFile);
                return;
            end

            marginLon = 1.00;
            marginLat = 1.00;

            lonMin = lonLim(1) - marginLon;
            lonMax = lonLim(2) + marginLon;
            latMin = latLim(1) - marginLat;
            latMax = latLim(2) + marginLat;

            nLines = 0;

            for k = 1:numel(S)

                if isfield(S, 'X') && isfield(S, 'Y')
                    x = S(k).X;
                    y = S(k).Y;
                elseif isfield(S, 'Lon') && isfield(S, 'Lat')
                    x = S(k).Lon;
                    y = S(k).Lat;
                else
                    continue;
                end

                idx = x >= lonMin & x <= lonMax & ...
                    y >= latMin & y <= latMax;

                if any(idx)

                    xPlot = x;
                    yPlot = y;

                    xPlot(~idx) = NaN;
                    yPlot(~idx) = NaN;

                    plot(ax, xPlot, yPlot, ...
                        'Color', lineColor, ...
                        'LineWidth', lineWidth, ...
                        'HandleVisibility', 'off');

                    nLines = nLines + 1;
                end
            end

            disp(['Plotted lines from ', shpFile, ': ', num2str(nLines)])
        end

        function fit = fitLocalTwoParameterModel(app, results, modelID)

            h     = [0; results.lags_valid(:)];
            C_emp = [results.C0_emp; results.C_emp_valid(:)];

            if isfield(results, 'pair_count_valid')
                w = [max(results.pair_count_valid); results.pair_count_valid(:)];
            else
                w = ones(size(h));
            end

            valid = isfinite(h) & isfinite(C_emp) & isfinite(w);

            h     = h(valid);
            C_emp = C_emp(valid);
            w     = w(valid);

            if isempty(h) || isempty(C_emp)
                error('No valid empirical covariance points available for fitting.');
            end

            sigma2_0 = max(results.C0_emp, eps);

            if isfield(results, 'Lhalf_emp') && isfinite(results.Lhalf_emp) && results.Lhalf_emp > 0

                switch lower(modelID)

                    case 'gm2'

                        a_0 = results.Lhalf_emp;

                    case 'gm3'

                        a_0 = results.Lhalf_emp;

                    case 'tscherningrapp'
                        a_0 = results.Lhalf_emp;

                    case 'spherical'

                        a_0 = results.Lhalf_emp / 0.35;

                    case 'cauchy'

                        a_0 = results.Lhalf_emp;

                    otherwise
                        a_0 = results.Lhalf_emp;
                end

            else
                a_0 = max(h) / 3;
            end

            a_0 = max(a_0, eps);


            theta0 = [log(sigma2_0); log(a_0)];

            obj = @(th) localModelObjective(app, th, h, C_emp, w, modelID);

            options = optimset( ...
                'Display','off', ...
                'TolX',1e-8, ...
                'TolFun',1e-8, ...
                'MaxIter',1000, ...
                'MaxFunEvals',4000);

            theta_hat = fminsearch(obj, theta0, options);

            sigma2_hat = exp(theta_hat(1));
            a_hat      = exp(theta_hat(2));

            C_fit = localCovarianceModel(app, h, sigma2_hat, a_hat, modelID);

            sse = obj(theta_hat);

            model_fun = @(hh) localCovarianceModel(app, hh, sigma2_hat, a_hat, modelID);

            Lhalf_mod = localHalfCovarianceDistance(app, model_fun, sigma2_hat, max(h));

            fit.modelID    = modelID;
            fit.model_name = localModelName(app, modelID);

            fit.sigma2    = sigma2_hat;
            fit.a         = a_hat;
            fit.theta_hat = theta_hat;
            fit.C_fit     = C_fit;
            fit.sse0      = obj(theta0);
            fit.sse       = sse;
            fit.Lhalf_mod = Lhalf_mod;
            fit.model_fun  = model_fun;
            if strcmpi(modelID, 'cauchy')
                fit.alpha = 2;
            end

        end

        function sse = localModelObjective(app, theta, h, C_emp, w, modelID)


            sigma2 = exp(theta(1));
            a      = exp(theta(2));

            C_mod = localCovarianceModel(app, h, sigma2, a, modelID);

            if any(~isfinite(C_mod))
                sse = Inf;
                return;
            end

            residuals = C_emp - C_mod;

            sse = sum(w .* residuals.^2, 'omitnan');
        end

        function C = localCovarianceModel(app, h, sigma2, a, modelID)
            originalSize = size(h);

            h_vec = h(:);
            x = h_vec ./ a;

            switch lower(modelID)

                case 'gm2'
                    % 2nd-order Gauss-Markov:
                    % C(d) = C0 (1+r) exp(-r), r = sqrt(3)*d/a
                    r = sqrt(3) .* x;
                    C_vec = sigma2 .* (1 + r) .* exp(-r);

                case 'gm3'
                    % 3rd-order Gauss-Markov:
                    % C(d) = C0 (1+r+r^2/3) exp(-r), r = sqrt(5)*d/a
                    r = sqrt(5) .* x;
                    C_vec = sigma2 .* (1 + r + (r.^2)./3) .* exp(-r);

                case 'spherical'
                    % Spherical:
                    % C(d)=C0[1 - 3/2(d/a) + 1/2(d/a)^3], for 0 <= d <= a
                    % C(d)=0, for d > a
                    C_vec = zeros(size(h_vec));

                    idx = h_vec <= a;
                    u = h_vec(idx) ./ a;

                    C_vec(idx) = sigma2 .* (1 - 1.5.*u + 0.5.*u.^3);
                    C_vec(~idx) = 0;

                case 'cauchy'
                    % Cauchy:
                    % C(d)=C0[1+(d/a)^2]^(-alpha)
                    alpha = 2;
                    C_vec = sigma2 .* (1 + x.^2).^(-alpha);

                otherwise
                    error('Unknown local covariance model: %s', modelID);
            end

            C = reshape(C_vec, originalSize);

        end

        function name = localModelName(app, modelID)

            switch lower(modelID)

                case 'gm2'
                    name = '2nd-order Gauss-Markov';

                case 'gm3'
                    name = '3rd-order Gauss-Markov';

                case 'tscherningrapp'
                    name = 'Tscherning-Rapp';

                case 'spherical'
                    name = 'Spherical';

                case 'cauchy'
                    name = 'Cauchy';

                otherwise
                    name = modelID;
            end
        end

        function Lhalf = localHalfCovarianceDistance(app, model_fun, sigma2, hmax)
            % Υπολογισμός της απόστασης όπου C(d)=0.5*C(0).

            if isempty(hmax) || ~isfinite(hmax) || hmax <= 0
                Lhalf = NaN;
                return;
            end

            h_plot = linspace(0, hmax, 2000).';
            C_plot = model_fun(h_plot);

            target = 0.5 * sigma2;

            k = find(C_plot <= target, 1, 'first');

            if isempty(k)
                Lhalf = NaN;
                return;
            end

            if k == 1
                Lhalf = h_plot(1);
                return;
            end

            x1 = h_plot(k-1);
            x2 = h_plot(k);
            y1 = C_plot(k-1);
            y2 = C_plot(k);

            if y2 == y1
                Lhalf = x2;
            else
                Lhalf = x1 + (target - y1) * (x2 - x1) / (y2 - y1);
            end
        end

        function fit = fitLocalTscherningRappModel(app, results)

            % FITLOCALTSCHERNINGRAPPMODEL
            % Normalized local Tscherning-Rapp covariance model.
            %
            % The model is normalized so that C(0) = C0_emp.
            % The lower degree N is estimated by grid search.
            % The spectral parameter s is estimated by bounded minimization.

            h     = [0; results.lags_valid(:)];
            C_emp = [results.C0_emp; results.C_emp_valid(:)];

            if isfield(results, 'pair_count_valid')
                w = [max(results.pair_count_valid); results.pair_count_valid(:)];
            else
                w = ones(size(h));
            end

            valid = isfinite(h) & isfinite(C_emp) & isfinite(w);

            h     = h(valid);
            C_emp = C_emp(valid);
            w     = w(valid);

            if isempty(h) || isempty(C_emp)
                error('No valid empirical covariance points available for fitting.');
            end

            % Fixed model constants
            B      = 24;
            nmax   = round(app.NmaxEditField.Value);
            Rearth = 6371;

            if isempty(nmax) || isnan(nmax) || ~isfinite(nmax)
                error('nmax must be a finite positive integer.');
            end

            if nmax < 50
                error('nmax is too small. Please use nmax >= 50.');
            end

            if nmax <= 321
                error('nmax must be greater than the maximum lower-degree candidate N. Please use nmax > 320.');
            end

            % Empirical variance
            C0_emp = max(results.C0_emp, eps);

            % Candidate lower-degree cutoffs.
            % N = 2 corresponds to the old implementation, because the sum starts at n = 3.
            N_candidates = [2 5 10 15 20 25 30 40 50 60 70 80 90 100 120 140 160 180 200 240 280 320];

            N_candidates = N_candidates(N_candidates + 1 < nmax);

            if isempty(N_candidates)
                error('No valid lower-degree candidates are available. Please increase nmax.');
            end

            bestN   = NaN;
            bestS   = NaN;
            bestSSE = Inf;

            options = optimset( ...
                'Display','off', ...
                'TolX',1e-8);

            for iN = 1:numel(N_candidates)

                N = N_candidates(iN);

                if N + 1 >= nmax
                    continue;
                end

                obj_s = @(s) tscherningRappObjective( ...
                    app, s, h, C_emp, w, C0_emp, B, N, nmax, Rearth);

                try
                    s_try = fminbnd(obj_s, 0.50, 0.99999, options);
                    sse_try = obj_s(s_try);
                catch
                    s_try = NaN;
                    sse_try = Inf;
                end

                if isfinite(sse_try) && sse_try < bestSSE
                    bestSSE = sse_try;
                    bestS   = s_try;
                    bestN   = N;
                end
            end

            if ~isfinite(bestSSE)
                error('Tscherning-Rapp fitting failed.');
            end

            C_fit = covTscherningRappLocal(app, h, C0_emp, bestS, B, bestN, nmax, Rearth);

            model_fun = @(hh) covTscherningRappLocal(app, hh, C0_emp, bestS, B, bestN, nmax, Rearth);

            sigma2_hat = C0_emp;

            Lhalf_mod = localHalfCovarianceDistance(app, model_fun, sigma2_hat, max(h));

            fit.modelID    = 'tscherningrapp';
            fit.model_name = 'Tscherning-Rapp';

            fit.C0         = C0_emp;
            fit.B          = B;
            fit.N          = bestN;
            fit.s          = bestS;
            fit.nmax       = nmax;
            fit.Rearth     = Rearth;

            fit.sigma2     = sigma2_hat;

            % Compatibility with the general structure.
            % For Tscherning-Rapp this is not a length scale in km.
            fit.a          = bestS;

            fit.theta_hat  = [bestS; bestN];
            fit.C_fit      = C_fit;
            fit.sse        = bestSSE;
            fit.sse0       = NaN;
            fit.Lhalf_mod  = Lhalf_mod;
            fit.model_fun  = model_fun;
        end

        function sse = tscherningRappObjective(app, s, h_km, C_emp, w, C0, B, N, nmax, Rearth)
            % Objective function for normalized local Tscherning-Rapp model.

            if s <= 0 || s >= 1 || C0 <= 0 || N < 2 || N + 1 >= nmax
                sse = Inf;
                return;
            end

            C_mod = covTscherningRappLocal(app, h_km, C0, s, B, N, nmax, Rearth);

            if any(~isfinite(C_mod))
                sse = Inf;
                return;
            end

            residuals = C_emp - C_mod;

            sse = sum(w .* residuals.^2, 'omitnan');
        end

        function C = covTscherningRappLocal(app, h_km, C0, s, B, N, nmax, Rearth)

            % COVTSCHERNINGRAPPLOCAL
            % Normalized local Tscherning-Rapp covariance model.
            %
            % C(h) = C0 * S(h) / S(0)
            %
            % Important:
            % The function preserves the original size of h_km.
            % This is necessary because LSC passes distance matrices.

            originalSize = size(h_km);
            h_vec = h_km(:);

            if C0 <= 0 || s <= 0 || s >= 1 || N < 2 || N + 1 >= nmax
                C = NaN(originalSize);
                return;
            end

            nStart = max(N + 1, 3);

            % Convert spherical distance h [km] to angular distance psi [rad]
            psi = h_vec ./ Rearth;
            x   = cos(psi);

            S = zeros(size(h_vec));

            % Legendre recurrence
            Pnm2 = ones(size(x));   % P_0(x)
            Pnm1 = x;               % P_1(x)

            for n = 2:nmax

                Pn = ((2*n - 1).*x.*Pnm1 - (n - 1).*Pnm2) ./ n;

                if n >= nStart
                    coeff = (n - 1) ./ ((n - 2) .* (n + B));
                    S = S + coeff .* (s.^(n + 2)) .* Pn;
                end

                Pnm2 = Pnm1;
                Pnm1 = Pn;
            end

            % Value of the same series at h = 0.
            % At h = 0, P_n(1) = 1.
            S0 = 0;

            for n = nStart:nmax
                coeff = (n - 1) ./ ((n - 2) .* (n + B));
                S0 = S0 + coeff .* (s.^(n + 2));
            end

            if S0 <= 0 || ~isfinite(S0)
                C = NaN(originalSize);
                return;
            end

            % Normalization: C(0) = C0
            C_vec = C0 .* (S ./ S0);

            % Restore original input size
            C = reshape(C_vec, originalSize);
        end


        function data = ensureObservationStd(app, data)
            % ENSUREOBSERVATIONSTD
            % Ensures that observation data always contain std_grav.
            %
            % If std_grav exists in the imported/generated data, it is used.
            % If not, a default observation standard deviation is assigned.

            if isempty(data)
                error('Observation data are empty.');
            end

            n = numel(data.grav);

            if isfield(data, 'std_grav') && ~isempty(data.std_grav)

                data.std_grav = data.std_grav(:);

                if numel(data.std_grav) ~= n
                    error('std_grav size does not match the number of observations.');
                end

                badStd = ~isfinite(data.std_grav) | data.std_grav <= 0;

                if any(badStd)
                    defaultStd = app.ObsarvationnoiseEditField.Value;

                    if isempty(defaultStd) || isnan(defaultStd) || defaultStd <= 0
                        defaultStd = 0.5;
                    end

                    data.std_grav(badStd) = defaultStd;
                end

            else

                defaultStd = app.ObsarvationnoiseEditField.Value;

                if isempty(defaultStd) || isnan(defaultStd) || defaultStd <= 0
                    defaultStd = 0.5;
                end

                data.std_grav = defaultStd .* ones(n, 1);
            end
        end

        function [trainData, testData] = splitRealDataForLSC(app, data, train_fraction, seed)
            % SPLITREALDATAFORLSC
            % Random train/test split for real imported observation data.
            %
            % train_fraction: percentage/100 of observations used as training data.

            data = ensureObservationStd(app, data);

            n = numel(data.grav);

            if n < 5
                error('At least 5 observations are required for random train/test split.');
            end

            if train_fraction <= 0 || train_fraction >= 1
                error('Train split must be between 0 and 100 percent.');
            end

            rng(seed);

            idx = randperm(n);

            nTrain = round(train_fraction * n);

            if nTrain < 2
                error('Training set is too small. Increase the train split percentage.');
            end

            if nTrain >= n
                error('Test set is empty. Reduce the train split percentage.');
            end

            trainIdx = idx(1:nTrain);
            testIdx  = idx(nTrain+1:end);

            trainData.id       = data.id(trainIdx);
            trainData.lat      = data.lat(trainIdx);
            trainData.lon      = data.lon(trainIdx);
            trainData.grav     = data.grav(trainIdx);
            trainData.std_grav = data.std_grav(trainIdx);

            testData.id       = data.id(testIdx);
            testData.lat      = data.lat(testIdx);
            testData.lon      = data.lon(testIdx);
            testData.grav     = data.grav(testIdx);
            testData.std_grav = data.std_grav(testIdx);
        end

        function plotMapBoundariesForGeoid(app, ax, lonLim, latLim)
            % First pass: white thick background line
            plotMapBoundariesCustom(app, ax, lonLim, latLim, ...
                [1 1 1], 2.60, ...
                [1 1 1], 2.00);

            % Second pass: dark blue / grey visible line
            plotMapBoundariesCustom(app, ax, lonLim, latLim, ...
                [0.02 0.18 0.38], 1.20, ...
                [0.35 0.35 0.35], 0.80);
        end

        function plotMapBoundariesCustom(app, ax, lonLim, latLim, ...
                coastColor, coastWidth, borderColor, borderWidth)
            if isdeployed
                mapFolder = fullfile(ctfroot, 'Land boundaries');
            else
                mapFolder = fullfile('C:\Users\teots\Documents\MATLAB\DiplThesis\TT_THESIS\Land boundaries');
            end

            coastFile  = fullfile(mapFolder, 'ne_10m_coastline.shp');
            borderFile = fullfile(mapFolder, 'ne_10m_admin_0_boundary_lines_land.shp');

            hold(ax, 'on');

            if isfile(coastFile)
                plotSingleShapefile(app, ax, coastFile, lonLim, latLim, coastColor, coastWidth);
            else
                warning('Coastline shapefile not found: %s', coastFile);
            end

            if isfile(borderFile)
                plotSingleShapefile(app, ax, borderFile, lonLim, latLim, borderColor, borderWidth);
            else
                warning('Boundary shapefile not found: %s', borderFile);
            end

        end

        function plotPredictionErrorTable(app)
            if ~strcmp(app.PredictionMode, 'synthetic') && ...
                    ~strcmp(app.PredictionMode, 'real_validation')
                error('Prediction error table is only available for train/test validation mode.');
            end

            if isempty(app.PredictionTruth) || isempty(app.PredictionResiduals)
                error('No validation errors available. The table cannot be created for unknown prediction points.');
            end

            predType = app.PredictiontypeDropDown.Value;

            if ~strcmp(predType, 'Gravity anomaly -> Gravity anomaly')
                error('Prediction error table is only available for gravity anomaly validation.');
            end

            dataCheck = app.SyntheticCheckData;

            n = numel(app.PredictionTruth);

            if isfield(dataCheck, 'id') && numel(dataCheck.id) == n
                id = dataCheck.id(:);
            else
                id = (1:n).';
            end

            lat = dataCheck.lat(:);
            lon = dataCheck.lon(:);

            observed  = app.PredictionTruth(:);
            predicted = app.PredictionResults(:);
            residual  = predicted - observed;
            absResidual = abs(residual);

            if isempty(app.PredictionStd)
                predStd = NaN(n,1);
                normResidual = NaN(n,1);
            else
                predStd = app.PredictionStd(:);
                normResidual = residual ./ predStd;
            end

            T = table( ...
                id, lat, lon, observed, predicted, residual, absResidual, predStd, normResidual, ...
                'VariableNames', { ...
                'id', ...
                'lat_deg', ...
                'lon_deg', ...
                'observed_mGal', ...
                'predicted_mGal', ...
                'residual_mGal', ...
                'abs_residual_mGal', ...
                'prediction_std_mGal', ...
                'standardized_residual'});

            app.PredictionErrorTable = T;

            app.PredictionErrorUITable.Data = T;
            app.PredictionErrorUITable.ColumnName = T.Properties.VariableNames;
            app.PredictionErrorUITable.RowName = {};
        end

        function plotPredictionErrorMatrixOnAxes(app)
            if ~app.PredictionReady
                error('No prediction results available. Run prediction first.');
            end

            if isempty(app.PredictionErrorCov)
                error('Prediction error covariance matrix is not available.');
            end

            ax = app.UIAxes5_2;

            D = app.PredictionErrorCov;

            if size(D,1) ~= size(D,2)
                error('Prediction error covariance matrix must be square.');
            end

            cla(ax);
            colorbar(ax, 'off');

            ax.FontName = 'Times New Roman';
            ax.FontSize = 11;
            ax.LineWidth = 1.0;
            ax.TickLabelInterpreter = 'latex';

            imagesc(ax, D);
            axis(ax, 'image');
            box(ax, 'on');
            grid(ax, 'off');

            colormap(ax, turbo);

            cb = colorbar(ax);
            cb.TickLabelInterpreter = 'latex';

            predType = app.PredictiontypeDropDown.Value;

            if strcmp(predType, 'Gravity anomaly -> Gravity anomaly')
                cb.Label.String = '$D_{\hat{g}}$ (mGal$^2$)';
                ttl = 'Prediction error covariance';
            else
                cb.Label.String = '$D_{\hat{N}}$ (m$^2$)';
                ttl = 'Prediction error covariance';
            end

            cb.Label.Interpreter = 'latex';
            cb.Label.FontSize = 11;

            xlabel(ax, 'Prediction point index $j$', ...
                'Interpreter', 'latex', ...
                'FontSize', 12);

            ylabel(ax, 'Prediction point index $i$', ...
                'Interpreter', 'latex', ...
                'FontSize', 12);

            title(ax, ttl, ...
                'Interpreter', 'latex', ...
                'FontWeight', 'bold', ...
                'FontSize', 13);

            fixPointPredictionAxesLabelClipping(app, ax);

            diagD = diag(D);

            txt = { ...
                sprintf('Prediction points: %d', size(D,1)), ...
                sprintf('Min diagonal: %.4e', min(diagD, [], 'omitnan')), ...
                sprintf('Max diagonal: %.4e', max(diagD, [], 'omitnan')), ...
                sprintf('Mean diagonal: %.4e', mean(diagD, 'omitnan'))};

            xl = xlim(ax);
            yl = ylim(ax);

            text(ax, ...
                xl(1) + 0.03*(xl(2)-xl(1)), ...
                yl(1) + 0.07*(yl(2)-yl(1)), ...
                txt, ...
                'Interpreter', 'latex', ...
                'VerticalAlignment', 'top', ...
                'BackgroundColor', 'w', ...
                'EdgeColor', [0.35 0.35 0.35], ...
                'Margin', 6);
        end

        function resetPredictionRunStatus(app)

            app.StatusidleLabel.Text = 'Status: running...';
            app.Lamp_2.Color = [1 0.65 0];   % πορτοκαλί/κίτρινο για running

            app.ObservationsusedLabel.Text = 'Observations used: -';
            app.ModelLabel_4.Text = 'Model: -';
            app.TargetquantityLabel.Text = 'Target quantity: -';
            app.ComputationtimeLabel_2.Text = 'Computation time: -';

            app.RMSELabel.Text = 'RMSE: -';
            app.BiasLabel.Text = 'Bias: -';
            app.StdresidualLabel.Text = 'Std residual: -';
            app.MeanpredictionstdLabel.Text = 'Mean prediction std: -';
            app.MinMaxpredictionstdLabel.Text = 'Min/Max prediction std: -';

            app.ModeLabel.Text = 'Mode: -';
            app.StageLabel.Text = 'Stage: -';
            app.DatasplitpointsLabel.Text = 'Data split / points: -';
            app.MatrixsizeLabel.Text = 'Matrix size: -';
            app.NumericalstatusLabel.Text = 'Numerical status: -';

            app.RMSELabel.Enable = 'off';
            app.BiasLabel.Enable = 'off';
            app.StdresidualLabel.Enable = 'off';
            app.MeanpredictionstdLabel.Enable = 'off';
            app.MinMaxpredictionstdLabel.Enable = 'off';

            app.PredictionResults = [];
            app.PredictionResiduals = [];
            app.PredictionStd = [];
            app.PredictionTruth = [];
            app.PredictionErrorTable = table();
            app.PredictionErrorCov = [];
            app.PredictionReady = false;

            drawnow;
        end

        function updateTscherningRappNmaxControl(app)
            isTR = strcmp(app.AnalyticalModelDropDown.Value, 'Tscherning-Rapp') && ...
                strcmp(app.DistanceTypeDropDown.Value, 'Spherical');

            if isTR

                app.NmaxEditField.Enable = 'on';

                if isempty(app.NmaxEditField.Value) || ...
                        isnan(app.NmaxEditField.Value) || ...
                        app.NmaxEditField.Value <= 0

                    app.NmaxEditField.Value = 720;
                end

            else

                app.NmaxEditField.Value = 720;
                app.NmaxEditField.Enable = 'off';

            end

        end

        function fixPointPredictionAxesLabelClipping(app, ax)

            % Small pause so MATLAB updates axis label positions
            drawnow limitrate;

            % Keep a little extra space around axis labels
            try
                ti = ax.TightInset;
                ax.LooseInset = max(ti + [0.04 0.03 0.03 0.03], ...
                    [0.12 0.08 0.06 0.06]);
            catch
                % Some UIAxes versions may not allow all inset edits.
                % The normalized label-position correction below is the main fix.
            end

            % Move y-label slightly inside the axes container so it is not clipped
            try
                ax.YLabel.Units = 'normalized';
                ax.YLabel.Position(1) = -0.070;
            catch
            end

            % Move x-label slightly upward if needed
            try
                ax.XLabel.Units = 'normalized';
                ax.XLabel.Position(2) = -0.080;
            catch
            end

            ax.Clipping = 'on';

        end

        function  fit = addCovarianceFitQualityIndex(app, fit, results)

            % ADDCOVARIANCEFITQUALITYINDEX
            % Computes a weighted RMSE of the covariance fit.
            %
            % SSE units   : mGal^4
            % WRMSE units : mGal^2

            h     = [0; results.lags_valid(:)];
            C_emp = [results.C0_emp; results.C_emp_valid(:)];

            if isfield(results, 'pair_count_valid') && ~isempty(results.pair_count_valid)
                w = [max(results.pair_count_valid); results.pair_count_valid(:)];
            else
                w = ones(size(h));
            end

            valid = isfinite(h) & isfinite(C_emp) & isfinite(w) & w > 0;

            h     = h(valid);
            C_emp = C_emp(valid);
            w     = w(valid);

            if isempty(h)
                fit.wrmse_cov = NaN;
                return;
            end

            C_mod = fit.model_fun(h);

            residuals = C_emp - C_mod;

            fit.sse = sum(w .* residuals.^2, 'omitnan');
            fit.wmse_cov = fit.sse / sum(w, 'omitnan');
            fit.wrmse_cov = sqrt(fit.wmse_cov);
        end

        function plotPredictionAccuracyOnAxes(app)
            % PLOTPREDICTIONACCURACYONAXES
            % Observed vs predicted scatter plot for train/test validation.
            %
            % This plot is available only when known check values exist.

            if ~strcmp(app.PredictionMode, 'synthetic') && ...
                    ~strcmp(app.PredictionMode, 'real_validation')
                error('Prediction accuracy plot is only available for train/test validation mode.');
            end

            if isempty(app.PredictionTruth) || isempty(app.PredictionResults)
                error('No validation values available. Run train/test validation first.');
            end

            predType = app.PredictiontypeDropDown.Value;

            if ~strcmp(predType, 'Gravity anomaly -> Gravity anomaly')
                error('Prediction accuracy plot is only available for gravity anomaly validation.');
            end

            ax = app.UIAxes5_3;

            observed  = app.PredictionTruth(:);
            predicted = app.PredictionResults(:);

            residual = predicted - observed;

            valid = isfinite(observed) & isfinite(predicted) & isfinite(residual);

            observed  = observed(valid);
            predicted = predicted(valid);
            residual  = residual(valid);

            if numel(observed) < 2
                error('At least two valid check points are required for the accuracy plot.');
            end

            stylePredictionAxes(app, ax, ...
                'Observed $\Delta g$ (mGal)', ...
                'Predicted $\Delta \hat{g}$ (mGal)', ...
                'Observed vs predicted');

            % Scatter plot colored by residuals
            hSc = scatter(ax, observed, predicted, ...
                36, residual, ...
                'filled', ...
                'MarkerFaceAlpha', 0.85, ...
                'MarkerEdgeColor', 'k', ...
                'LineWidth', 0.35);

            colormap(ax, turbo);

            cb = colorbar(ax);
            cb.TickLabelInterpreter = 'latex';
            cb.Label.String = '$\hat{g}-g$ (mGal)';
            cb.Label.Interpreter = 'latex';
            cb.Label.FontSize = 11;

            % Common axis limits
            allVals = [observed; predicted];
            minVal = min(allVals, [], 'omitnan');
            maxVal = max(allVals, [], 'omitnan');

            pad = 0.05 * (maxVal - minVal);
            if pad == 0 || ~isfinite(pad)
                pad = 1;
            end

            lims = [minVal - pad, maxVal + pad];

            xlim(ax, lims);
            ylim(ax, lims);

            % 1:1 reference line
            hOne = plot(ax, lims, lims, '--k', ...
                'LineWidth', 1.25);

            % Linear fit line
            p = polyfit(observed, predicted, 1);
            xx = linspace(lims(1), lims(2), 200);
            yy = polyval(p, xx);

            hFit = plot(ax, xx, yy, '-', ...
                'Color', [0.35 0.35 0.35], ...
                'LineWidth', 1.40);

            % Statistics
            rmseVal = sqrt(mean(residual.^2, 'omitnan'));
            biasVal = mean(residual, 'omitnan');
            stdVal  = std(residual, 'omitnan');

            R = corrcoef(observed, predicted, 'Rows', 'complete');

            if numel(R) >= 4 && isfinite(R(1,2))
                rVal = R(1,2);
                r2Val = rVal^2;
            else
                rVal = NaN;
                r2Val = NaN;
            end

            txt = { ...
                sprintf('Check points: %d', numel(observed)), ...
                sprintf('$RMSE = %.3f\\ \\mathrm{mGal}$', rmseVal), ...
                sprintf('$Bias = %.3f\\ \\mathrm{mGal}$', biasVal), ...
                sprintf('$\\sigma_e = %.3f\\ \\mathrm{mGal}$', stdVal), ...
                sprintf('$R^2 = %.3f$', r2Val), ...
                sprintf('Fit: $y = %.3f x %+ .3f$', p(1), p(2))};

            xl = xlim(ax);
            yl = ylim(ax);

            text(ax, ...
                xl(1) + 0.04*(xl(2)-xl(1)), ...
                yl(2) - 0.05*(yl(2)-yl(1)), ...
                txt, ...
                'Interpreter', 'latex', ...
                'VerticalAlignment', 'top', ...
                'BackgroundColor', 'w', ...
                'EdgeColor', [0.35 0.35 0.35], ...
                'Margin', 6);

            legend(ax, [hSc hOne hFit], ...
                {'Check points', '1:1 line', 'Linear fit'}, ...
                'Interpreter', 'latex', ...
                'Location', 'southeast');

            axis(ax, 'square');
            grid(ax, 'on');
            box(ax, 'on');

            fixPointPredictionAxesLabelClipping(app, ax);

            hold(ax, 'off');
        end

        function updatePredictionProgress(app, modeText, stageText, splitText, matrixText, numericText)
            %UPDATEPREDICTIONPROGRESS Update LSC progress labels during prediction.

            if nargin < 2 || isempty(modeText)
                modeText = '-';
            end

            if nargin < 3 || isempty(stageText)
                stageText = '-';
            end

            if nargin < 4 || isempty(splitText)
                splitText = '-';
            end

            if nargin < 5 || isempty(matrixText)
                matrixText = '-';
            end

            if nargin < 6 || isempty(numericText)
                numericText = '-';
            end

            app.ModeLabel.Text = ['Mode: ', char(modeText)];
            app.StageLabel.Text = ['Stage: ', char(stageText)];
            app.DatasplitpointsLabel.Text = ['Data split / points: ', char(splitText)];
            app.MatrixsizeLabel.Text = ['Matrix size: ', char(matrixText)];
            app.NumericalstatusLabel.Text = ['Numerical status: ', char(numericText)];

            % Also print in Command Window, so you can see that MATLAB is still running.
            fprintf('[LSC] Mode: %s | Stage: %s | Data split / points: %s | Matrix size: %s | Numerical status: %s\n', ...
                char(modeText), char(stageText), char(splitText), char(matrixText), char(numericText));

            drawnow limitrate;
        end

        function fixPointPredictionAxesLabels(app, ax)
            %FIXPOINTPREDICTIONAXESLABELS Keep axis labels visible inside UIAxes.

            if isempty(ax) || ~isvalid(ax)
                return;
            end

            ax.XLabel.Units = 'normalized';
            ax.YLabel.Units = 'normalized';

            % Move Y label slightly to the right so it is not clipped.
            ax.YLabel.Position = [-0.010 0.50 0];

            % Keep X label visible.
            ax.XLabel.Position = [0.50 -0.090 0];

            ax.XLabel.FontSize = 11;
            ax.YLabel.FontSize = 11;

            drawnow limitrate;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateFieldButton
        function CreateFieldButtonPushed(app, event)

            % User inputs
            n_points = app.NumberofPointsEditField.Value;
            lat_min  = app.LatitudeMinEditField.Value;
            lat_max  = app.LatitudeMaxEditField.Value;
            lon_min  = app.LongitudeMinEditField.Value;
            lon_max  = app.LongitudeMaxEditField.Value;

            seed        = app.RandomGenerationSeedSpinner.Value;
            grid_dim    = app.GridResolutionEditField.Value;
            sigma_cells = app.SmoothingScaleEditField.Value;
            signal_std  = app.SignalStdEditField.Value;

            if ~validateInputs(app)
                return;
            end
            data = Synthetic_field(n_points, lat_min, lat_max, lon_min,...
                lon_max, seed, grid_dim, sigma_cells, signal_std);

            app.GeneratedData = data;

            StatusGenerated(app, n_points, lat_min, lat_max, lon_min, lon_max)

        end

        % Value changed function: UseSuggestedSettingsCheckBox
        function UseSuggestedSettingsCheckBoxValueChanged(app, event)
            value = app.UseSuggestedSettingsCheckBox.Value;

            if value == true
                app.GridResolutionEditField.Value          = 200;
                app.SmoothingScaleEditField.Value = 15;
                app.SignalStdEditField.Value     = 25;

                app.GridResolutionEditField.Enable          = 'off';
                app.SmoothingScaleEditField.Enable = 'off';
                app.SignalStdEditField.Enable     = 'off';

            else
                app.GridResolutionEditField.Value          = 0;
                app.SmoothingScaleEditField.Value = 0;
                app.SignalStdEditField.Value     = 0;

                app.GridResolutionEditField.Enable          = 'on';
                app.SmoothingScaleEditField.Enable = 'on';
                app.SignalStdEditField.Enable     = 'on';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure, ...
                'Clear all results?', ...
                'Confirm', ...
                'Options', {'Yes','Cancel'});

            if strcmp(selection,'Yes')
                app.NumberofPointsEditField.Value    = 0;
                app.LatitudeMinEditField.Value       = 0;
                app.LatitudeMaxEditField.Value       = 0;
                app.LongitudeMinEditField.Value      = 0;
                app.LongitudeMaxEditField.Value      = 0;
                app.RandomGenerationSeedSpinner.Value          = 0;

                app.GridResolutionEditField.Value          = 0;
                app.SmoothingScaleEditField.Value = 0;
                app.SignalStdEditField.Value     = 0;

                initialStatus(app)

                app.GeneratedData = [];

                % Check box
                app.UseSuggestedSettingsCheckBox.Value = false;

                app.GridResolutionEditField.Enable = 'on';
                app.SmoothingScaleEditField.Enable = 'on';
                app.SignalStdEditField.Enable = 'on';
            end


        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            if isempty(app.GeneratedData)
                uialert(app.UIFigure, ...
                    'No data available for export. Please create a field first.', ...
                    'Export Error');
                return;
            end
            % Convert struct to table
            T = table(app.GeneratedData.id, ...
                app.GeneratedData.lat, ...
                app.GeneratedData.lon, ...
                app.GeneratedData.grav, ...
                app.GeneratedData.std_grav, ...
                'VariableNames', {'id','lat','lon','grav','std_grav'});

            % Ask user where to save
            [file, path] = uiputfile({'*.csv'; '*.txt'}, 'Save Exported Data');

            % If user cancels
            if isequal(file,0) || isequal(path,0)
                return;
            end

            filename = fullfile(path, file);

            % Write file
            writetable(T, filename);

            uiconfirm(app.UIFigure, ...
                'Data exported successfully.', ...
                'Export Complete', ...
                'Options', {'OK'})
        end

        % Button pushed function: CreatePlotButton
        function CreatePlotButtonPushed(app, event)
            if isempty(app.GeneratedData)
                uialert(app.UIFigure, ...
                    'No field data available. Please create a field first.', ...
                    'Plot Error');
                return;
            end

            lon  = app.GeneratedData.lon(:);
            lat  = app.GeneratedData.lat(:);
            grav = app.GeneratedData.grav(:);

            plotType = app.PlottypeDropDown.Value;
            ax = app.UIAxes;


            cla(ax);
            app.UIAxes.Visible = 'on';

            styleMapAxes(app, ax, plotType);
            hold(ax, 'on');


            lonLim = [min(lon), max(lon)];
            latLim = [min(lat), max(lat)];

            xlim(ax, lonLim);
            ylim(ax, latLim);

            ax.XLimMode = 'manual';
            ax.YLimMode = 'manual';

            switch plotType

                case 'Simple Scatter'

                    scatter(ax, lon, lat, ...
                        22, ...
                        [0.85 0.05 0.05], ...
                        'filled', ...
                        'MarkerFaceAlpha', 0.60, ...
                        'MarkerEdgeAlpha', 0.60);

                    plotMapBoundariesClean(app, ax, lonLim, latLim);

                case 'Colored Scatter'

                    scatter(ax, lon, lat, ...
                        28, grav, ...
                        'filled', ...
                        'MarkerFaceAlpha', 0.85, ...
                        'MarkerEdgeAlpha', 0.85);

                    plotMapBoundariesClean(app, ax, lonLim, latLim);

                    addMapColorbar(app, ax, ...
                        'Gravity anomaly $\Delta g$ (mGal)');

                case 'Interpolated Surface'

                    [Lonq, Latq, Gravq] = makeInterpolationGrid(app, lon, lat, grav, 180);

                    contourf(ax, Lonq, Latq, Gravq, ...
                        40, ...
                        'LineStyle', 'none');

                    plotMapBoundariesClean(app, ax, lonLim, latLim);

                    scatter(ax, lon, lat, ...
                        7, 'k', ...
                        'filled', ...
                        'MarkerFaceAlpha', 0.20, ...
                        'MarkerEdgeAlpha', 0.20);

                    addMapColorbar(app, ax, ...
                        'Gravity anomaly $\Delta g$ (mGal)');

                otherwise

                    uialert(app.UIFigure, ...
                        'Unknown plot type.', ...
                        'Plot Error');

                    hold(ax, 'off');
                    return;
            end


            xlim(ax, lonLim);
            ylim(ax, latLim);

            ax.XLimMode = 'manual';
            ax.YLimMode = 'manual';


            axis(ax, 'normal');

            grid(ax, 'on');
            box(ax, 'on');

            xlabel(ax, '$\lambda$ (deg)', 'Interpreter', 'latex');
            ylabel(ax, '$\phi$ (deg)', 'Interpreter', 'latex');

            hold(ax, 'off');
        end

        % Button pushed function: ClearplotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes3);
            colorbar(app.UIAxes3, 'off');
        end

        % Value changed function: DefinemaxdistancelimitCheckBox
        function DefinemaxdistancelimitCheckBoxValueChanged(app, event)
            if app.DefinemaxdistancelimitCheckBox.Value

                app.DistancelimitEditField.Enable = 'on';

                if isempty(app.DistancelimitEditField.Value) || ...
                        isnan(app.DistancelimitEditField.Value) || ...
                        app.DistancelimitEditField.Value <= 0

                    app.DistancelimitEditField.Value = [];
                end

            else
                app.DistancelimitEditField.Enable = 'off';
                app.DistancelimitEditField.Value = [];
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)

            % Select data source

            switch app.DatatypeDropDown.Value
                case 'Synthetic Data'
                    data = app.GeneratedData;

                    if isempty(data)
                        uialert(app.UIFigure, ...
                            'No synthetic data available. Please create a field from previous tab.', ...
                            'Compute Error');
                        return;
                    end

                case 'User Data'
                    data = app.ImportedData;

                    if isempty(data)
                        uialert(app.UIFigure, ...
                            'No user data imported. Please import a file first.', ...
                            'Compute Error');
                        return;
                    end
            end

            lat  = data.lat;
            lon  = data.lon;
            grav = data.grav;


            % 2. Read distance type

            distanceType = app.DistanceTypeDropDown.Value;

            % 3. Optional max distance limit
            if app.DefinemaxdistancelimitCheckBox.Value

                max_dist = app.DistancelimitEditField.Value;

                if isempty(max_dist) || isnan(max_dist) || max_dist <= 0
                    uialert(app.UIFigure, ...
                        'Max distance must be a positive value when the distance limit is enabled.', ...
                        'Invalid Input');
                    return;
                end

            else
                % No maximum distance limit
                max_dist = [];
            end

            % Automatic bin width
            bin_width = [];


            % Compute empirical covariance
            try
                switch distanceType
                    case 'Spherical'
                        results = Empirical_Model(lat, lon, grav, bin_width, max_dist);

                    case 'Euclidean'
                        results = Empirical_Model_Planar(lat, lon, grav, bin_width, max_dist);

                    otherwise
                        error('Unknown distance type selection.');
                end

            catch ME
                app.ComputationstateLabel.Visible = "on";
                app.ComputationstateLabel.Text = 'Unsuccessful Computation';
                app.ComputationstateLabel.FontColor = [1 0 0];

                uialert(app.UIFigure, ME.message, 'Compute Error');
                return;
            end

            app.EmpiricalResults = results;

            % -----------------------------
            % 5. Plot empirical covariance
            % -----------------------------
            plotEmpiricalCovarianceOnAxes(app, results);

            % -----------------------------
            % 6. Update labels
            % -----------------------------
            app.NumberofPointsLabel.Text = sprintf('Number of Points: %d', numel(lat));
            app.DistancetypeLabel.Text   = sprintf('Distance type: %s', distanceType);

            app.RegiongridLabel.Text = sprintf( ...
                'Region grid: [%.2f, %.2f] x [%.2f, %.2f]', ...
                min(lat), max(lat), min(lon), max(lon));

            if isfield(data, 'std_grav') && ~isempty(data.std_grav)
                app.DatastdavailableLabel.Text = 'Data std: available from file';
            else
                app.DatastdavailableLabel.Text = 'Data std: not available - default std will be used';
            end

            app.MindistanceLabel.Text = sprintf('Min distance: %.3f km', min(results.d_pairs));
            app.MaxdistanceLabel.Text = sprintf('Max distance: %.3f km', results.max_dist);
            app.NumberofbinsLabel.Text = sprintf('Number of bins: %d', results.nBins);
            app.EmpiricalVarianceC0Label.Text = sprintf('Empirical Variance C(0): %.4f', results.C0_emp);

            if isnan(results.Lhalf_emp)
                app.Correlationlength12Label.Text = 'Correlation length xi1/2: not estimated';
            else
                app.Correlationlength12Label.Text = sprintf('Correlation length xi1/2: %.3f km', results.Lhalf_emp);
            end

            app.ComputationstateLabel.Visible = "on";
            app.ComputationstateLabel.Text = 'Computation completed successfully';
            app.ComputationstateLabel.FontColor = [0 0.5 0];

            % update model availability after compute/distance choice
            updateAnalyticalModelOptions(app);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [file, path] = uigetfile({'*.txt;*.csv', 'Data Files (*.txt, *.csv)'; ...
                '*.*', 'All Files (*.*)'}, ...
                'Select gravity data file');

            if isequal(file,0) || isequal(path,0)
                return;
            end

            filename = fullfile(path, file);

            try
                data = read_gravity_data(filename);
                app.ImportedData = data;

                uiconfirm(app.UIFigure, ...
                    'Data imported successfully.', ...
                    'import Complete', ...
                    'Options', {'OK'})
            catch ME
                uialert(app.UIFigure, ME.message, 'Import Error');
            end
        end

        % Drop down opening function: AnalyticalModelDropDown
        function AnalyticalModelDropDownOpening(app, event)
            updateAnalyticalModelOptions(app);
            updateTscherningRappNmaxControl(app);
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            if isempty(app.EmpiricalResults)
                uialert(app.UIFigure, ...
                    'No empirical covariance available. Please press Compute first.', ...
                    'Fit Error');
                return;
            end

            selectedModel = app.AnalyticalModelDropDown.Value;

            if strcmp(app.DistanceTypeDropDown.Value, 'Euclidean') && ...
                    ~strcmp(app.AnalyticalModelDropDown.Value, 'Forsberg Planar')

                uialert(app.UIFigure, ...
                    'For Euclidean distances, only the Forsberg Planar model is available.', ...
                    'Fit Error');
                return;
            end

            if strcmp(selectedModel, 'Tscherning-Rapp')

                if ~strcmp(app.DistanceTypeDropDown.Value, 'Spherical')
                    uialert(app.UIFigure, ...
                        'Tscherning-Rapp model is only available for spherical distances.', ...
                        'Invalid Model Selection');
                    return;
                end

                nmaxUser = app.NmaxEditField.Value;

                if isempty(nmaxUser) || isnan(nmaxUser) || ~isfinite(nmaxUser)
                    uialert(app.UIFigure, ...
                        'nmax must be a finite positive integer.', ...
                        'Invalid nmax');
                    return;
                end

                if mod(nmaxUser, 1) ~= 0
                    uialert(app.UIFigure, ...
                        'nmax must be an integer value.', ...
                        'Invalid nmax');
                    return;
                end

                if nmaxUser < 50
                    uialert(app.UIFigure, ...
                        'nmax is too small. Please use nmax >= 50.', ...
                        'Invalid nmax');
                    return;
                end

                if nmaxUser <= 321
                    uialert(app.UIFigure, ...
                        'nmax must be greater than the maximum lower-degree candidate N. Please use nmax > 320.', ...
                        'Invalid nmax');
                    return;
                end

                if nmaxUser > 3000
                    choice = uiconfirm(app.UIFigure, ...
                        ['Large nmax values may significantly increase computation time, ' ...
                        'especially during LSC prediction. Do you want to continue?'], ...
                        'Large nmax Warning', ...
                        'Options', {'Continue','Cancel'}, ...
                        'DefaultOption', 'Cancel', ...
                        'CancelOption', 'Cancel');

                    if strcmp(choice, 'Cancel')
                        return;
                    end
                end
            end

            try
                switch selectedModel


                    case 'Exponential'
                        fit = fit_exponential_model(app.EmpiricalResults);

                    case 'Hirvonen'
                        fit = fit_hirvonen_model(app.EmpiricalResults);

                    case 'Gaussian'
                        fit = fit_gaussian_model(app.EmpiricalResults);

                    case 'Forsberg Planar'

                        if ~strcmp(app.DistanceTypeDropDown.Value, 'Euclidean')
                            uialert(app.UIFigure, ...
                                'Forsberg Planar can only be used with Euclidean distances.', ...
                                'Fit Error');
                            return;
                        end

                        fit = fit_forsberg_planar_model(app.EmpiricalResults);


                    case '2nd-order Gauss-Markov'
                        fit = fitLocalTwoParameterModel(app, app.EmpiricalResults, 'gm2');

                    case '3rd-order Gauss-Markov'
                        fit = fitLocalTwoParameterModel(app, app.EmpiricalResults, 'gm3');

                    case 'Tscherning-Rapp'
                        fit = fitLocalTscherningRappModel(app, app.EmpiricalResults);

                    case 'Spherical'
                        fit = fitLocalTwoParameterModel(app, app.EmpiricalResults, 'spherical');

                    case 'Cauchy'
                        fit = fitLocalTwoParameterModel(app, app.EmpiricalResults, 'cauchy');

                    otherwise
                        error('Selected analytical model is not connected.');
                end

                if ~isfield(fit, 'modelID')
                    fit.modelID = lower(strrep(selectedModel, ' ', '_'));
                end

                if ~isfield(fit, 'model_name')
                    fit.model_name = selectedModel;
                end

                if ~isfield(fit, 'a')
                    fit.a = NaN;
                end

                if ~isfield(fit, 'Lhalf_mod')
                    fit.Lhalf_mod = NaN;
                end

                fit = addCovarianceFitQualityIndex(app, fit, app.EmpiricalResults);

                app.FitResults = fit;


            catch ME
                uialert(app.UIFigure, ME.message, 'Fit Error');
                return;
            end

            if isfield(fit, 'modelID') && strcmpi(fit.modelID, 'tscherningrapp')
                app.ModelnameLabel.Text = sprintf('Model name: %s (nmax = %d)', ...
                    fit.model_name, fit.nmax);
            else
                app.ModelnameLabel.Text = sprintf('Model name: %s', fit.model_name);
            end

            app.ModelVarianceC0Label.Text = sprintf('Model Variance C(0): %.4e', fit.sigma2);

            if isfinite(fit.Lhalf_mod)
                app.Correlationlength12Label_2.Text = sprintf('Correlation length xi1/2: %.3f km', fit.Lhalf_mod);
            else
                app.Correlationlength12Label_2.Text = 'Correlation length xi1/2: not estimated';
            end

            app.FittedSSELabel.Text = sprintf('Fit WRMSE: %.4f mGal^2', fit.wrmse_cov);

            appendFitToComparisonTable(app, fit);

            plotEmpiricalVsAnalyticalOnAxes(app, app.EmpiricalResults, app.FitResults);

            updateLSCSettingsFromFit(app);
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.EmpiricalResults = [];
            app.FitResults = [];
            app.ImportedData = [];

            initialStatusEmpirical(app);

            cla(app.UIAxes2);

            app.ModelnameLabel.Text = 'Model name: -';
            app.ModelVarianceC0Label.Text = 'Model Variance C(0): -';
            app.Correlationlength12Label_2.Text = 'Correlation length xi1/2: -';
            app.FittedSSELabel.Text = 'Fit WRMSE: -';
            app.UITable.Data = {};

            updateLSCSettingsFromFit(app);
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            app.FitResults = [];
            app.ComparisonTableData = {};

            app.ModelnameLabel.Text = 'Model name: -';
            app.ModelVarianceC0Label.Text = 'Model Variance C(0): -';
            app.Correlationlength12Label_2.Text = 'Correlation length xi1/2: -';
            app.FittedSSELabel.Text = 'Fit WRMSE: -';

            app.UITable.Data = {};
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if ~isempty(app.EmpiricalResults)
                plotEmpiricalCovarianceOnAxes(app, app.EmpiricalResults);
            else
                cla(app.UIAxes2);
            end

            % Προαιρετικά: καθάρισε το current fit object
            app.FitResults = [];

            % Reset fitting labels
            app.ModelnameLabel.Text = 'Model name: -';
            app.ModelVarianceC0Label.Text = 'Model Variance C(0): -';
            app.Correlationlength12Label_2.Text = 'Correlation length xi1/2: -';
            app.FittedSSELabel.Text = 'Fitted SSE: -';
        end

        % Value changed function: EditdefaultvalueCheckBox
        function EditdefaultvalueCheckBoxValueChanged(app, event)
            if app.EditdefaultvalueCheckBox.Value
                app.TestsplitEditField.Enable = 'on';
            else
                app.TestsplitEditField.Enable = 'off';
                app.TestsplitEditField.Value = 80;
            end
        end

        % Value changed function: EditdefaultstdCheckBox
        function EditdefaultstdCheckBoxValueChanged(app, event)
            if app.EditdefaultstdCheckBox.Value
                app.ObsarvationnoiseEditField.Enable = 'on';
            else
                app.ObsarvationnoiseEditField.Enable = 'off';
                app.ObsarvationnoiseEditField.Value = 0.5;
            end
        end

        % Button pushed function: CreatePlotButton_2
        function CreatePlotButton_2Pushed(app, event)
            try
                plotDataFieldOnAxes(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Data Field Plot Error');
            end
        end

        % Button pushed function: CreatePlotButton_3
        function CreatePlotButton_3Pushed(app, event)
            try
                plotPredictionMapOnAxes(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Prediction Map Plot Error');
            end
        end

        % Button pushed function: CreatePlotButton_4
        function CreatePlotButton_4Pushed(app, event)
            try
                plotValidationScatterOnAxes(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Std Plot Error');
            end
        end

        % Button pushed function: CreatePlotButton_5
        function CreatePlotButton_5Pushed(app, event)
            try
                plotResidualHistogramOnAxes(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Error Map Plot Error');
            end
        end

        % Button pushed function: CreatePlotButton_6
        function CreatePlotButton_6Pushed(app, event)
            try
                plotGeoidMapOnAxes(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Comparison Plot Error');
            end
        end

        % Button pushed function: RunButton_5
        function RunButton_5Pushed(app, event)
            resetPredictionRunStatus(app)
            tStart = tic;

            try
                app.ComputationtimeLabel_2.Text = 'Computation time: running...';
                app.StatusidleLabel.Text = 'Status: running synthetic prediction...';
                app.Lamp_2.Color = [1 0.6 0];
                drawnow;

                updatePredictionProgress(app, ...
                    'Synthetic train/test validation', ...
                    'Checking input data and fitted model', ...
                    '-', '-', '-');

                if isempty(app.GeneratedData)
                    error('No synthetic field available. Please create field first.');
                end

                if isempty(app.FitResults)
                    error('No fitted covariance model available. Please complete Tab 2 first.');
                end

                predType = app.PredictiontypeDropDown.Value;

                updateLSCSettingsFromFit(app);

                train_fraction = app.TestsplitEditField.Value / 100;
                seedPred = app.RandomSeedSpinner_2.Value;
                reg_rel = 1e-10;

                if train_fraction <= 0 || train_fraction >= 1
                    error('Train split %% must be between 0 and 100.');
                end

                [trainData, testData] = split_synthetic_data_for_lsc( ...
                    app.GeneratedData, train_fraction, seedPred);

                app.SyntheticTrainData = trainData;
                app.SyntheticCheckData = testData;

                if app.EditdefaultstdCheckBox.Value
                    trainData.std_grav(:) = app.ObsarvationnoiseEditField.Value;
                end

                updatePredictionProgress(app, ...
                    'Synthetic train/test validation', ...
                    'Train/test split completed', ...
                    sprintf('Train: %d | Test: %d', numel(trainData.id), numel(testData.id)), ...
                    sprintf('Coo: %d x %d | Cpo: %d x %d | Cpp: %d x %d', ...
                    numel(trainData.id), numel(trainData.id), ...
                    numel(testData.id), numel(trainData.id), ...
                    numel(testData.id), numel(testData.id)), ...
                    'Ready for LSC');

                switch predType
                    case 'Gravity anomaly -> Gravity anomaly'

                        updatePredictionProgress(app, ...
                            'Synthetic train/test validation', ...
                            'Running LSC prediction', ...
                            sprintf('Train: %d | Test: %d', numel(trainData.id), numel(testData.id)), ...
                            sprintf('A: %d x %d', numel(trainData.id), numel(trainData.id)), ...
                            'Solving system');

                        pred = lsc_predict_general( ...
                            trainData.lat, trainData.lon, trainData.grav, trainData.std_grav, ...
                            testData.lat, testData.lon, ...
                            app.FitResults, app.DistanceTypeDropDown.Value, reg_rel);

                        pred_values  = pred.g_hat;
                        pred_std     = pred.std_hat;
                        truth_values = testData.grav;

                    case 'Gravity anomaly -> Geoid height'

                        updatePredictionProgress(app, ...
                            'Synthetic train/test validation', ...
                            'Running LSC prediction', ...
                            sprintf('Train: %d | Test: %d', numel(trainData.id), numel(testData.id)), ...
                            sprintf('A: %d x %d', numel(trainData.id), numel(trainData.id)), ...
                            'Solving system');

                        G   = app.GeoidGainG;
                        rho = app.GeoidCrossCorrelation;
                        muN = app.GeoidMeanOffset;

                        pred = lsc_predict_geoid_from_gravity( ...
                            trainData.lat, trainData.lon, trainData.grav, trainData.std_grav, ...
                            testData.lat, testData.lon, ...
                            app.FitResults, app.DistanceTypeDropDown.Value, ...
                            G, rho, muN, reg_rel);

                        pred_values  = pred.N_hat;
                        pred_std     = pred.std_hat;
                        truth_values = [];

                    otherwise
                        error('Unknown prediction type.');
                end

                updatePredictionProgress(app, ...
                    'Synthetic train/test validation', ...
                    'LSC completed', ...
                    sprintf('Train: %d | Test: %d', numel(trainData.id), numel(testData.id)), ...
                    sprintf('D_pred: %d x %d', size(pred.D_pred,1), size(pred.D_pred,2)), ...
                    sprintf('rcond(A): %.3e', pred.rcondA));


                app.PredictionResults = pred_values;
                app.PredictionStd     = pred_std;
                app.PredictionMode    = 'synthetic';
                app.PredictionReady   = true;
                app.PredictionErrorCov = pred.D_pred;

                if isempty(truth_values)
                    app.PredictionResiduals = [];
                    app.PredictionTruth     = [];

                    app.RMSELabel.Enable = 'off';
                    app.BiasLabel.Enable = 'off';
                    app.StdresidualLabel.Enable = 'off';

                    app.RMSELabel.Text = 'RMSE: -';
                    app.BiasLabel.Text = 'Bias: -';
                    app.StdresidualLabel.Text = 'Std residual: -';

                else
                    residuals = pred_values - truth_values;

                    app.PredictionResiduals = residuals;
                    app.PredictionTruth     = truth_values;

                    rmseVal = sqrt(mean(residuals.^2, 'omitnan'));
                    biasVal = mean(residuals, 'omitnan');
                    stdVal  = std(residuals, 'omitnan');

                    app.RMSELabel.Enable = 'on';
                    app.BiasLabel.Enable = 'on';
                    app.StdresidualLabel.Enable = 'on';

                    app.RMSELabel.Text = sprintf('RMSE: %.4f', rmseVal);
                    app.BiasLabel.Text = sprintf('Bias: %.4f', biasVal);
                    app.StdresidualLabel.Text = sprintf('Std residual: %.4f', stdVal);
                end

                meanStd = mean(pred_std, 'omitnan');
                minStd  = min(pred_std);
                maxStd  = max(pred_std);

                app.ObservationsusedLabel.Text = sprintf('Observations used: %d', numel(trainData.id));
                app.ModelLabel_4.Text = sprintf('Model: %s', app.FitResults.model_name);
                app.TargetquantityLabel.Text = sprintf('Target quantity: %s', predType);
                app.ComputationtimeLabel_2.Text = sprintf('Computation time: %.2f sec', toc(tStart));

                app.MeanpredictionstdLabel.Enable = 'on';
                app.MinMaxpredictionstdLabel.Enable = 'on';

                app.MeanpredictionstdLabel.Text = sprintf('Mean prediction std: %.4f', meanStd);
                app.MinMaxpredictionstdLabel.Text = sprintf('Min/Max prediction std: %.4f / %.4f', minStd, maxStd);

                app.StatusidleLabel.Text = 'Status: synthetic prediction completed';
                app.Lamp_2.Color = 'green';
                drawnow;

            catch ME
                app.PredictionReady = false;
                app.ComputationtimeLabel_2.Text = sprintf('Computation time: %.2f sec', toc(tStart));
                app.StatusidleLabel.Text = ['Status: ', ME.message];
                app.Lamp_2.Color = 'red';
                drawnow;
                uialert(app.UIFigure, ME.message, 'Synthetic Prediction Error');
            end
        end

        % Button pushed function: ImportPredictionFileButton
        function ImportPredictionFileButtonPushed(app, event)
            [file, path] = uigetfile({'*.txt;*.csv', 'Prediction files (*.txt, *.csv)'; ...
                '*.*', 'All Files (*.*)'}, ...
                'Select prediction points file');

            if isequal(file,0) || isequal(path,0)
                return;
            end

            filename = fullfile(path, file);

            try
                pred_data = read_prediction_points(filename);

                app.PredictionInputData = pred_data;
                app.PredictionInputFile = filename;

                app.PredictionfilenotloadedLabel.Text = ['Prediction file: ', file];
                app.FormatLabel.Text = 'Format: [id, latitude, longitude, height]';
                app.PredictionpointsLabel.Text = sprintf('Prediction points: %d', pred_data.n_points);

                app.StatusidleLabel.Text = 'Status: prediction file loaded';
                app.Lamp_2.Color = [1 0.6 0];

            catch ME
                app.PredictionInputData = [];
                app.PredictionInputFile = "";

                app.PredictionfilenotloadedLabel.Text = 'Prediction file: not loaded';
                app.FormatLabel.Text = 'Format: -';
                app.PredictionpointsLabel.Text = 'Prediction points: -';

                app.StatusidleLabel.Text = ['Status: ', ME.message];
                app.Lamp_2.Color = 'red';

                uialert(app.UIFigure, ME.message, 'Import Prediction File Error');
            end
        end

        % Button pushed function: ClearPlotButton
        function ClearplotButtonPushed(app, event)
            cla(app.UIAxes);
            colorbar(app.UIAxes, 'off');
        end

        % Button pushed function: ClearplotButton_2
        function ClearplotButton_2Pushed(app, event)
            cla(app.UIAxes4);
            colorbar(app.UIAxes4, 'off');
        end

        % Button pushed function: ClearplotButton_3
        function ClearplotButton_3Pushed(app, event)
            cla(app.UIAxes5);
            colorbar(app.UIAxes5, 'off');
        end

        % Button pushed function: ClearplotButton_4
        function ClearplotButton_4Pushed(app, event)
            cla(app.UIAxes6);
            colorbar(app.UIAxes6, 'off');
        end

        % Button pushed function: ClearplotButton_5
        function ClearplotButton_5Pushed(app, event)
            cla(app.UIAxes7);
            colorbar(app.UIAxes7, 'off');
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            try
                exportAxesToFile(app, app.UIAxes3);
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Plot Error');
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButton_2Pushed(app, event)
            try
                exportAxesToFile(app, app.UIAxes4);
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Plot Error');
            end
        end

        % Button pushed function: ExportPlotButton_3
        function ExportPlotButton_3Pushed(app, event)
            try
                exportAxesToFile(app, app.UIAxes5);
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Plot Error');
            end
        end

        % Button pushed function: ExportPlotButton_4
        function ExportPlotButton_4Pushed(app, event)
            try
                exportAxesToFile(app, app.UIAxes6);
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Plot Error');
            end
        end

        % Button pushed function: ExportPlotButton_5
        function ExportPlotButton_5Pushed(app, event)
            try
                exportAxesToFile(app, app.UIAxes7);
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Plot Error');
            end
        end

        % Value changed function: DatatypeDropDown
        function DatatypeDropDownValueChanged(app, event)
            if strcmp(app.DatatypeDropDown.Value, 'User Data')
                app.ImportDataButton.Enable = 'on';
            end

            if strcmp(app.DatatypeDropDown.Value, 'Synthetic Data')
                app.ImportDataButton.Enable = 'off';
            end
        end

        % Value changed function: NumberofPointsEditField
        function NumberofPointsEditFieldValueChanged2(app, event)
            n_points = app.NumberofPointsEditField.Value;
            if n_points > 5000
                uialert(app.UIFigure, ...
                    'Too many points, be aware of ram size.', ...
                    'Invalid Input');
                return;
            end
        end

        % Button pushed function: RunButton_4
        function RunButton_4Pushed(app, event)
            resetPredictionRunStatus(app)
            tStart = tic;

            try
                app.StatusidleLabel.Text = 'Status: running real-data prediction...';
                app.Lamp_2.Color = [1 0.6 0];
                drawnow;

                updatePredictionProgress(app, ...
                    'Real data prediction file', ...
                    'Checking observation and prediction files', ...
                    '-', '-', '-');

                % -----------------------------
                % Basic checks
                % -----------------------------
                if isempty(app.FitResults)
                    error('No fitted covariance model available. Please complete Tab 2 first.');
                end

                if isempty(app.PredictionInputData)
                    error('No prediction file loaded.');
                end

                predType = app.PredictiontypeDropDown.Value;

                data_obs = getCurrentObservationData(app);

                updatePredictionProgress(app, ...
                    'Real data prediction file', ...
                    'Input data prepared', ...
                    sprintf('Observations: %d | Prediction: %d', ...
                    numel(data_obs.id), numel(app.PredictionInputData.id)), ...
                    sprintf('Coo: %d x %d | Cpo: %d x %d | Cpp: %d x %d', ...
                    numel(data_obs.id), numel(data_obs.id), ...
                    numel(app.PredictionInputData.id), numel(data_obs.id), ...
                    numel(app.PredictionInputData.id), numel(app.PredictionInputData.id)), ...
                    'Ready for LSC');

                updateLSCSettingsFromFit(app);

                % Optional fixed noise override
                if app.EditdefaultstdCheckBox.Value
                    data_obs.std_grav(:) = app.ObsarvationnoiseEditField.Value;
                end

                reg_rel = 1e-10;

                updatePredictionProgress(app, ...
                    'Real data prediction file', ...
                    'Running LSC prediction', ...
                    sprintf('Observations: %d | Prediction: %d', ...
                    numel(data_obs.id), numel(app.PredictionInputData.id)), ...
                    sprintf('A: %d x %d', numel(data_obs.id), numel(data_obs.id)), ...
                    'Solving system');

                % -----------------------------
                % Prediction branch
                % -----------------------------
                switch predType

                    case 'Gravity anomaly -> Gravity anomaly'

                        pred_out = run_lsc_prediction_user_general( ...
                            data_obs, app.PredictionInputData, app.FitResults, ...
                            app.DistanceTypeDropDown.Value, reg_rel);

                        updatePredictionProgress(app, ...
                            'Real data prediction file', ...
                            'LSC completed', ...
                            sprintf('Observations: %d | Prediction: %d', ...
                            numel(data_obs.id), numel(pred_out.id)), ...
                            sprintf('D_pred: %d x %d', size(pred_out.D_pred,1), size(pred_out.D_pred,2)), ...
                            'Prediction ready');

                        app.PredictionResults = pred_out;
                        app.PredictionStd     = pred_out.std_hat;

                    case 'Gravity anomaly -> Geoid height'

                        G   = app.GeoidGainG;
                        rho = app.GeoidCrossCorrelation;
                        muN = app.GeoidMeanOffset;

                        pred_out = run_lsc_prediction_geoid_user( ...
                            data_obs, app.PredictionInputData, app.FitResults, ...
                            app.DistanceTypeDropDown.Value, G, rho, muN, reg_rel);

                        updatePredictionProgress(app, ...
                            'Real data prediction file', ...
                            'LSC completed', ...
                            sprintf('Observations: %d | Prediction: %d', ...
                            numel(data_obs.id), numel(pred_out.id)), ...
                            sprintf('D_pred: %d x %d', size(pred_out.D_pred,1), size(pred_out.D_pred,2)), ...
                            'Prediction ready');

                        app.PredictionResults = pred_out;
                        app.PredictionStd     = pred_out.std_hat;

                    otherwise
                        error('Unknown prediction type.');
                end

                % -----------------------------
                % Store general results
                % -----------------------------
                app.PredictionResiduals = [];
                app.PredictionTruth     = [];
                app.PredictionMode      = 'real';
                app.PredictionReady     = true;
                app.PredictionErrorCov = pred_out.D_pred;

                % -----------------------------
                % Update labels
                % -----------------------------
                app.ObservationsusedLabel.Text = sprintf('Observations used: %d', numel(data_obs.id));
                app.ModelLabel_4.Text = sprintf('Model: %s', app.FitResults.model_name);
                app.TargetquantityLabel.Text = sprintf('Target quantity: %s', predType);
                app.ComputationtimeLabel_2.Text = sprintf('Computation time: %.2f sec', toc(tStart));

                app.RMSELabel.Enable = 'off';
                app.BiasLabel.Enable = 'off';
                app.StdresidualLabel.Enable = 'off';

                app.RMSELabel.Text = 'RMSE: -';
                app.BiasLabel.Text = 'Bias: -';
                app.StdresidualLabel.Text = 'Std residual: -';

                app.MeanpredictionstdLabel.Enable = 'on';
                app.MinMaxpredictionstdLabel.Enable = 'on';

                app.MeanpredictionstdLabel.Text = sprintf( ...
                    'Mean prediction std: %.4f', mean(pred_out.std_hat, 'omitnan'));

                app.MinMaxpredictionstdLabel.Text = sprintf( ...
                    'Min/Max prediction std: %.4f / %.4f', ...
                    min(pred_out.std_hat), max(pred_out.std_hat));

                app.StatusidleLabel.Text = 'Status: real-data prediction completed';
                app.Lamp_2.Color = 'green';

            catch ME
                app.PredictionReady = false;
                app.StatusidleLabel.Text = ['Status: ', ME.message];
                app.Lamp_2.Color = 'red';
                uialert(app.UIFigure, ME.message, 'Real Data Prediction Error');
            end
        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
            selection = uiconfirm(app.UIFigure, ...
                'Clear all prediction results?', ...
                'Confirm', ...
                'Options', {'Yes','Cancel'});

            if strcmp(selection, 'Yes')
                initialStatusPrediction(app);
            end
        end

        % Value changed function: DistanceTypeDropDown
        function DistanceTypeDropDownValueChanged(app, event)
            updateAnalyticalModelOptions(app);

            app.FitResults = [];

            app.ModelnameLabel.Text = 'Model name: -';
            app.ModelVarianceC0Label.Text = 'Model Variance C(0): -';
            app.Correlationlength12Label_2.Text = 'Correlation length xi1/2: -';
            app.FittedSSELabel.Text = 'Fitted SSE: -';

            app.ModelLabel_3.Text = 'Model: -';
            app.DistancetypeLabel_2.Text = 'Distance type: -';

            updateTscherningRappNmaxControl(app);
        end

        % Button pushed function: RunButton_6
        function RunButton_6Pushed(app, event)
            resetPredictionRunStatus(app)
            tStart = tic;

            try
                app.StatusidleLabel.Text = 'Status: running real-data train/test validation...';
                app.Lamp_2.Color = [1 0.6 0];
                drawnow;

                updatePredictionProgress(app, ...
                    'Real train/test validation', ...
                    'Checking imported real data', ...
                    '-', '-', '-');

                % -----------------------------
                % Basic checks
                % -----------------------------
                if isempty(app.FitResults)
                    error('No fitted covariance model available. Please complete Tab 2 first.');
                end

                if ~strcmp(app.DatatypeDropDown.Value, 'User Data')
                    error('Real-data train/test validation requires Data type = User Data in Tab 2.');
                end

                if isempty(app.ImportedData)
                    error('No user data imported. Please import observation data first.');
                end

                predType = app.PredictiontypeDropDown.Value;

                % Get current real observation data and ensure std_grav exists
                data_obs = getCurrentObservationData(app);

                updateLSCSettingsFromFit(app);

                train_fraction = app.TestsplitEditField_2.Value / 100;
                seedPred = app.RandomSeedSpinner_3.Value;

                if train_fraction <= 0 || train_fraction >= 1
                    error('Train split %% must be between 0 and 100.');
                end

                % -----------------------------
                % Split real observations
                % -----------------------------
                [trainData, testData] = splitRealDataForLSC(app, data_obs, train_fraction, seedPred);

                % Reuse these properties for plotting validation datasets
                app.SyntheticTrainData = trainData;
                app.SyntheticCheckData = testData;

                % Optional fixed noise override
                if app.EditdefaultstdCheckBox.Value
                    obsNoise = app.ObsarvationnoiseEditField.Value;

                    if isempty(obsNoise) || isnan(obsNoise) || obsNoise <= 0
                        error('Observation noise must be a positive value.');
                    end

                    trainData.std_grav(:) = obsNoise;
                end

                reg_rel = 1e-10;

                updatePredictionProgress(app, ...
                    'Real train/test validation', ...
                    'Train/test split completed', ...
                    sprintf('Train: %d | Test: %d', numel(trainData.id), numel(testData.id)), ...
                    sprintf('Coo: %d x %d | Cpo: %d x %d | Cpp: %d x %d', ...
                    numel(trainData.id), numel(trainData.id), ...
                    numel(testData.id), numel(trainData.id), ...
                    numel(testData.id), numel(testData.id)), ...
                    'Ready for LSC');

                updatePredictionProgress(app, ...
                    'Real train/test validation', ...
                    'Running LSC prediction', ...
                    sprintf('Train: %d | Test: %d', numel(trainData.id), numel(testData.id)), ...
                    sprintf('A: %d x %d', numel(trainData.id), numel(trainData.id)), ...
                    'Solving system');

                % -----------------------------
                % LSC prediction
                % -----------------------------
                switch predType

                    case 'Gravity anomaly -> Gravity anomaly'

                        pred = lsc_predict_general( ...
                            trainData.lat, trainData.lon, trainData.grav, trainData.std_grav, ...
                            testData.lat, testData.lon, ...
                            app.FitResults, app.DistanceTypeDropDown.Value, reg_rel);

                        pred_values  = pred.g_hat;
                        pred_std     = pred.std_hat;
                        truth_values = testData.grav;

                    case 'Gravity anomaly -> Geoid height'

                        G   = app.GeoidGainG;
                        rho = app.GeoidCrossCorrelation;
                        muN = app.GeoidMeanOffset;

                        pred = lsc_predict_geoid_from_gravity( ...
                            trainData.lat, trainData.lon, trainData.grav, trainData.std_grav, ...
                            testData.lat, testData.lon, ...
                            app.FitResults, app.DistanceTypeDropDown.Value, ...
                            G, rho, muN, reg_rel);

                        pred_values  = pred.N_hat;
                        pred_std     = pred.std_hat;

                        % Για πραγματικά δεδομένα δεν υπάρχει γνωστή αλήθεια για N
                        truth_values = [];

                    otherwise
                        error('Unknown prediction type.');
                end

                updatePredictionProgress(app, ...
                    'Real train/test validation', ...
                    'LSC completed', ...
                    sprintf('Train: %d | Test: %d', numel(trainData.id), numel(testData.id)), ...
                    sprintf('D_pred: %d x %d', size(pred.D_pred,1), size(pred.D_pred,2)), ...
                    sprintf('rcond(A): %.3e', pred.rcondA));

                % -----------------------------
                % Store results
                % -----------------------------
                app.PredictionResults = pred_values;
                app.PredictionStd     = pred_std;
                app.PredictionMode    = 'real_validation';
                app.PredictionReady   = true;
                app.PredictionErrorCov = pred.D_pred;

                % -----------------------------
                % Validation statistics
                % -----------------------------
                if isempty(truth_values)

                    app.PredictionResiduals = [];
                    app.PredictionTruth     = [];

                    app.RMSELabel.Enable = 'off';
                    app.BiasLabel.Enable = 'off';
                    app.StdresidualLabel.Enable = 'off';

                    app.RMSELabel.Text = 'RMSE: -';
                    app.BiasLabel.Text = 'Bias: -';
                    app.StdresidualLabel.Text = 'Std residual: -';

                else

                    residuals = pred_values(:) - truth_values(:);

                    app.PredictionResiduals = residuals;
                    app.PredictionTruth     = truth_values(:);

                    rmseVal = sqrt(mean(residuals.^2, 'omitnan'));
                    biasVal = mean(residuals, 'omitnan');
                    stdVal  = std(residuals, 'omitnan');

                    app.RMSELabel.Enable = 'on';
                    app.BiasLabel.Enable = 'on';
                    app.StdresidualLabel.Enable = 'on';

                    app.RMSELabel.Text = sprintf('RMSE: %.4f', rmseVal);
                    app.BiasLabel.Text = sprintf('Bias: %.4f', biasVal);
                    app.StdresidualLabel.Text = sprintf('Std residual: %.4f', stdVal);
                end

                % -----------------------------
                % General status labels
                % -----------------------------
                meanStd = mean(pred_std, 'omitnan');
                minStd  = min(pred_std);
                maxStd  = max(pred_std);

                app.ObservationsusedLabel.Text = sprintf('Observations used: %d', numel(trainData.id));
                app.ModelLabel_4.Text = sprintf('Model: %s', app.FitResults.model_name);
                app.TargetquantityLabel.Text = sprintf('Target quantity: %s', predType);
                app.ComputationtimeLabel_2.Text = sprintf('Computation time: %.2f sec', toc(tStart));

                app.MeanpredictionstdLabel.Enable = 'on';
                app.MinMaxpredictionstdLabel.Enable = 'on';

                app.MeanpredictionstdLabel.Text = sprintf('Mean prediction std: %.4f', meanStd);
                app.MinMaxpredictionstdLabel.Text = sprintf('Min/Max prediction std: %.4f / %.4f', minStd, maxStd);

                app.PredictionfilenotloadedLabel.Text = 'Prediction mode: real train/test split';
                app.FormatLabel.Text = sprintf('Train/Test: %.1f%% / %.1f%%', ...
                    100*train_fraction, 100*(1-train_fraction));
                app.PredictionpointsLabel.Text = sprintf('Prediction points: %d', numel(testData.id));

                app.StatusidleLabel.Text = 'Status: real train/test validation completed';
                app.Lamp_2.Color = 'green';
                drawnow;

            catch ME

                app.PredictionReady = false;
                app.StatusidleLabel.Text = ['Status: ', ME.message];
                app.ComputationtimeLabel_2.Text = sprintf('Computation time: %.2f sec', toc(tStart));
                app.Lamp_2.Color = 'red';
                drawnow;

                uialert(app.UIFigure, ME.message, 'Real Train/Test Validation Error');

            end
        end

        % Value changed function: EditdefaultvalueCheckBox_2
        function EditdefaultvalueCheckBox_2ValueChanged(app, event)
            if app.EditdefaultvalueCheckBox_2.Value
                app.TestsplitEditField_2.Enable = 'on';
            else
                app.TestsplitEditField_2.Enable = 'off';
                app.TestsplitEditField_2.Value = 80;
            end
        end

        % Button pushed function: CreatePlotButton_7
        function CreatePlotButton_7Pushed(app, event)

            try
                plotPredictionErrorMatrixOnAxes(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Prediction Error Matrix Plot Error');
            end
        end

        % Button pushed function: ExportPlotButton_6
        function ExportPlotButton_6Pushed(app, event)
            try
                exportAxesToFile(app, app.UIAxes5_2);
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Plot Error');
            end
        end

        % Button pushed function: ClearplotButton_6
        function ClearplotButton_6Pushed(app, event)
            cla(app.UIAxes5_2);
            colorbar(app.UIAxes5_2, 'off');
        end

        % Value changed function: AnalyticalModelDropDown
        function AnalyticalModelDropDownValueChanged(app, event)
            updateTscherningRappNmaxControl(app);

            % Clear previous fit, because the selected model has changed
            app.FitResults = [];

            app.ModelnameLabel.Text = 'Model name: -';
            app.ModelVarianceC0Label.Text = 'Model Variance C(0): -';
            app.Correlationlength12Label_2.Text = 'Correlation length ξ1/2: -';
            app.FittedSSELabel.Text = 'Fitted SSE: -';

            updateLSCSettingsFromFit(app);

        end

        % Button pushed function: CreatePlotButton_8
        function CreatePlotButton_8Pushed(app, event)
            try
                plotPredictionAccuracyOnAxes(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Prediction Accuracy Plot Error');
            end
        end

        % Button pushed function: ExportPlotButton_7
        function ExportPlotButton_7Pushed(app, event)
            try
                exportAxesToFile(app, app.UIAxes5_3);
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Plot Error');
            end
        end

        % Button pushed function: ClearplotButton_7
        function ClearplotButton_7Pushed(app, event)
            cla(app.UIAxes5_3);
            colorbar(app.UIAxes5_3, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout25 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout25);
            app.SYNTHETICFIELDGENERATIONTab = uitab(app.TabGroup);
            app.SYNTHETICFIELDGENERATIONTab.Title = 'SYNTHETIC FIELD GENERATION';
            app.GridLayout26 = uigridlayout(app.SYNTHETICFIELDGENERATIONTab);
            app.UIAxes = uiaxes(app.GridLayout26);
            title(app.UIAxes, 'Synthetic Field')
            xlabel(app.UIAxes, 'Longitude (degrees)')
            ylabel(app.UIAxes, 'Latitude (degrees)')
            zlabel(app.UIAxes, 'Z')
            app.FIELDPARAMETERSPanel = uipanel(app.GridLayout26);
            app.FIELDPARAMETERSPanel.Title = 'FIELD PARAMETERS';
            app.GridLayout6 = uigridlayout(app.FIELDPARAMETERSPanel);
            app.RandomGenerationSeedSpinnerLabel = uilabel(app.GridLayout6);
            app.RandomGenerationSeedSpinnerLabel.Text = 'Random Generation Seed';
            app.RandomGenerationSeedSpinner = uispinner(app.GridLayout6);
            app.RandomGenerationSeedSpinner.Tooltip = {'Controls the random generation process. The same seed produces the same synthetic field.'};
            app.LatitudeMinEditFieldLabel = uilabel(app.GridLayout6);
            app.LatitudeMinEditFieldLabel.Text = 'Latitude Min';
            app.LatitudeMinEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LatitudeMaxEditFieldLabel = uilabel(app.GridLayout6);
            app.LatitudeMaxEditFieldLabel.Text = 'Latitude Max';
            app.LatitudeMaxEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LongitudeMinEditFieldLabel = uilabel(app.GridLayout6);
            app.LongitudeMinEditFieldLabel.Text = 'Longitude Min';
            app.LongitudeMinEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LongitudeMaxEditFieldLabel = uilabel(app.GridLayout6);
            app.LongitudeMaxEditFieldLabel.Text = 'Longitude Max';
            app.LongitudeMaxEditField = uieditfield(app.GridLayout6, 'numeric');
            app.NumberofPointsEditFieldLabel = uilabel(app.GridLayout6);
            app.NumberofPointsEditFieldLabel.Text = 'Number of Points';
            app.NumberofPointsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.NumberofPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointsEditFieldValueChanged2, true);
            app.NumberofPointsEditField.Tooltip = {'Number of randomly distributed observation points to be generated within the selected geographic region.'};
            app.degreesLabel = uilabel(app.GridLayout6);
            app.degreesLabel.Text = 'degrees';
            app.degreesLabel_2 = uilabel(app.GridLayout6);
            app.degreesLabel_2.Text = 'degrees';
            app.degreesLabel_3 = uilabel(app.GridLayout6);
            app.degreesLabel_3.Text = 'degrees';
            app.degreesLabel_4 = uilabel(app.GridLayout6);
            app.degreesLabel_4.Text = 'degrees';
            app.GENERATIONSETTINGSPanel = uipanel(app.GridLayout26);
            app.GENERATIONSETTINGSPanel.Title = 'GENERATION SETTINGS';
            app.GridLayout5 = uigridlayout(app.GENERATIONSETTINGSPanel);
            app.UseSuggestedSettingsCheckBox = uicheckbox(app.GridLayout5);
            app.UseSuggestedSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSuggestedSettingsCheckBoxValueChanged, true);
            app.UseSuggestedSettingsCheckBox.Tooltip = {'Automatically applies recommended generation settings for a stable and realistic synthetic gravity field.'};
            app.UseSuggestedSettingsCheckBox.Text = 'Use Suggested Settings';
            app.GridResolutionEditFieldLabel = uilabel(app.GridLayout5);
            app.GridResolutionEditFieldLabel.Text = 'Grid Resolution ';
            app.GridResolutionEditField = uieditfield(app.GridLayout5, 'numeric');
            app.GridResolutionEditField.Tooltip = {'Number of grid cells used for the internal generation of the synthetic field. Higher values increase spatial detail and computation time.'};
            app.SmoothingScaleEditFieldLabel = uilabel(app.GridLayout5);
            app.SmoothingScaleEditFieldLabel.Text = 'Smoothing Scale';
            app.SmoothingScaleEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SmoothingScaleEditField.Tooltip = {'Standard deviation of the Gaussian smoothing filter, expressed in grid cells. Larger values produce a smoother and more spatially correlated field.'};
            app.SignalStdEditFieldLabel = uilabel(app.GridLayout5);
            app.SignalStdEditFieldLabel.Text = 'Signal Std';
            app.SignalStdEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SignalStdEditField.Tooltip = {'Standard deviation of the generated synthetic gravity anomaly signal before measurement noise is added, expressed in mGal.'};
            app.cellsLabel_2 = uilabel(app.GridLayout5);
            app.cellsLabel_2.Text = 'cells';
            app.cellsLabel = uilabel(app.GridLayout5);
            app.cellsLabel.Text = 'cells';
            app.mGalLabel = uilabel(app.GridLayout5);
            app.mGalLabel.Text = 'mGal';
            app.FIELDSTATUSPanel = uipanel(app.GridLayout26);
            app.FIELDSTATUSPanel.Title = 'FIELD STATUS';
            app.GridLayout4 = uigridlayout(app.FIELDSTATUSPanel);
            app.StatusnotgeneratedLabel = uilabel(app.GridLayout4);
            app.StatusnotgeneratedLabel.Text = 'Status: not generated';
            app.PointsLabel = uilabel(app.GridLayout4);
            app.PointsLabel.Text = 'Points: -';
            app.LatituderangeLabel = uilabel(app.GridLayout4);
            app.LatituderangeLabel.Text = 'Latitude range: -';
            app.LongituderangeLabel = uilabel(app.GridLayout4);
            app.LongituderangeLabel.Text = 'Longitude range: - ';
            app.Lamp = uilamp(app.GridLayout4);
            app.Lamp.Tooltip = {'Red indicates that no field has been generated. Green indicates that the synthetic field is available.'};
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.CreateFieldButton = uibutton(app.GridLayout27, 'push');
            app.CreateFieldButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFieldButtonPushed, true);
            app.CreateFieldButton.Icon = fullfile(pathToMLAPP, 'icons', 'select.png');
            app.CreateFieldButton.Tooltip = {'Generate a synthetic gravity anomaly field using the selected parameters.'};
            app.CreateFieldButton.Text = 'Create Field';
            app.ExportDataButton = uibutton(app.GridLayout27, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Icon = fullfile(pathToMLAPP, 'icons', 'file-export.png');
            app.ExportDataButton.Tooltip = {'Export the generated synthetic field data to a CSV file.'};
            app.ExportDataButton.Text = 'Export Data';
            app.ResetButton = uibutton(app.GridLayout27, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'icons', 'rotate.png');
            app.ResetButton.Tooltip = {'Reset the input parameters, generated data and plot area to their initial state.'};
            app.ResetButton.Text = 'Reset';
            app.PlottingPanel = uipanel(app.GridLayout26);
            app.PlottingPanel.Title = 'Plotting';
            app.GridLayout8 = uigridlayout(app.PlottingPanel);
            app.ClearPlotButton = uibutton(app.GridLayout8, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButtonPushed, true);
            app.ClearPlotButton.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.ClearPlotButton.Tooltip = {'Clear the current plot without deleting the generated field data.'};
            app.ClearPlotButton.Text = 'Clear Plot';
            app.CreatePlotButton = uibutton(app.GridLayout8, 'push');
            app.CreatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePlotButtonPushed, true);
            app.CreatePlotButton.Icon = fullfile(pathToMLAPP, 'icons', 'chart.png');
            app.CreatePlotButton.Tooltip = {'Display the generated synthetic field using the selected plot type.'};
            app.CreatePlotButton.Text = 'Create Plot';
            app.PlottypeDropDownLabel = uilabel(app.GridLayout8);
            app.PlottypeDropDownLabel.Text = 'Plot type';
            app.PlottypeDropDown = uidropdown(app.GridLayout8);
            app.PlottypeDropDown.Items = {'Simple Scatter', 'Colored Scatter', 'Interpolated Surface'};
            app.PlottypeDropDown.Tooltip = {'Select the visualization method used to display the synthetic gravity anomaly field.'};
            app.PlottypeDropDown.Value = 'Simple Scatter';
            app.Image = uiimage(app.GridLayout26);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Frame 2.png');
            app.ANALYTICALMODELFITTINGTab = uitab(app.TabGroup);
            app.ANALYTICALMODELFITTINGTab.Title = 'ANALYTICAL MODEL FITTING';
            app.GridLayout28 = uigridlayout(app.ANALYTICALMODELFITTINGTab);
            app.UIAxes2 = uiaxes(app.GridLayout28);
            title(app.UIAxes2, 'Covarιance Function Comparison')
            xlabel(app.UIAxes2, 'd (km)')
            ylabel(app.UIAxes2, 'C(d)')
            zlabel(app.UIAxes2, 'Z')
            app.EMPIRIRCALCOVARIANCEPanel = uipanel(app.GridLayout28);
            app.EMPIRIRCALCOVARIANCEPanel.Title = 'EMPIRIRCAL COVARIANCE';
            app.GridLayout12 = uigridlayout(app.EMPIRIRCALCOVARIANCEPanel);
            app.ImportDataButton = uibutton(app.GridLayout12, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Icon = fullfile(pathToMLAPP, 'icons', 'file-import.png');
            app.ImportDataButton.Enable = 'off';
            app.ImportDataButton.Text = 'Import Data';
            app.ComputeButton = uibutton(app.GridLayout12, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Icon = fullfile(pathToMLAPP, 'icons', 'calc.png');
            app.ComputeButton.Text = 'Compute';
            app.DistancelimitEditFieldLabel = uilabel(app.GridLayout12);
            app.DistancelimitEditFieldLabel.Text = 'Distance limit ';
            app.DistancelimitEditField = uieditfield(app.GridLayout12, 'numeric');
            app.DistancelimitEditField.AllowEmpty = 'on';
            app.DistancelimitEditField.Enable = 'off';
            app.DistancelimitEditField.Value = [];
            app.REASULTSSTATUSPanel = uipanel(app.GridLayout12);
            app.REASULTSSTATUSPanel.Title = 'REASULTS/STATUS';
            app.GridLayout13 = uigridlayout(app.REASULTSSTATUSPanel);
            app.EmpiricalVarianceC0Label = uilabel(app.GridLayout13);
            app.EmpiricalVarianceC0Label.Text = 'Empirical Variance C(0): -';
            app.NumberofbinsLabel = uilabel(app.GridLayout13);
            app.NumberofbinsLabel.Text = 'Number of bins: -';
            app.MindistanceLabel = uilabel(app.GridLayout13);
            app.MindistanceLabel.Text = 'Min distance: - ';
            app.MaxdistanceLabel = uilabel(app.GridLayout13);
            app.MaxdistanceLabel.Text = 'Max distance: -';
            app.Correlationlength12Label = uilabel(app.GridLayout13);
            app.Correlationlength12Label.Text = 'Correlation length ξ1/2: -';
            app.FIELDSTATUSPanel_2 = uipanel(app.GridLayout12);
            app.FIELDSTATUSPanel_2.Title = 'FIELD STATUS';
            app.GridLayout14 = uigridlayout(app.FIELDSTATUSPanel_2);
            app.NumberofPointsLabel = uilabel(app.GridLayout14);
            app.NumberofPointsLabel.Text = 'Number of Points: -';
            app.DistancetypeLabel = uilabel(app.GridLayout14);
            app.DistancetypeLabel.Text = 'Distance type: -';
            app.RegiongridLabel = uilabel(app.GridLayout14);
            app.RegiongridLabel.Text = 'Region grid: -   ';
            app.DatastdavailableLabel = uilabel(app.GridLayout14);
            app.DatastdavailableLabel.Text = 'Data std: available';
            app.ComputationstateLabel = uilabel(app.GridLayout14);
            app.ComputationstateLabel.Visible = 'off';
            app.ComputationstateLabel.Text = 'Computation state  ';
            app.ResetButton_3 = uibutton(app.GridLayout14, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'rotate.png');
            app.ResetButton_3.Text = 'Reset';
            app.DatatypeDropDownLabel = uilabel(app.GridLayout12);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.GridLayout12);
            app.DatatypeDropDown.Items = {'Synthetic Data', 'User Data'};
            app.DatatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDownValueChanged, true);
            app.DatatypeDropDown.Value = 'Synthetic Data';
            app.DistanceTypeDropDownLabel = uilabel(app.GridLayout12);
            app.DistanceTypeDropDownLabel.Text = 'Distance Type';
            app.DistanceTypeDropDown = uidropdown(app.GridLayout12);
            app.DistanceTypeDropDown.Items = {'Euclidean', 'Spherical'};
            app.DistanceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DistanceTypeDropDownValueChanged, true);
            app.DistanceTypeDropDown.Value = 'Spherical';
            app.DefinemaxdistancelimitCheckBox = uicheckbox(app.GridLayout12);
            app.DefinemaxdistancelimitCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefinemaxdistancelimitCheckBoxValueChanged, true);
            app.DefinemaxdistancelimitCheckBox.Text = 'Define max distance limit';
            app.MaximumdegreeofexpansionEditFieldLabel = uilabel(app.GridLayout12);
            app.MaximumdegreeofexpansionEditFieldLabel.Enable = 'off';
            app.MaximumdegreeofexpansionEditFieldLabel.Text = 'Maximum degree of expansion';
            app.NmaxEditField = uieditfield(app.GridLayout12, 'numeric');
            app.NmaxEditField.Limits = [100 5000];
            app.NmaxEditField.RoundFractionalValues = 'on';
            app.NmaxEditField.AllowEmpty = 'on';
            app.NmaxEditField.Enable = 'off';
            app.NmaxEditField.Value = 720;
            app.FITTINGPanel = uipanel(app.GridLayout28);
            app.FITTINGPanel.Title = 'FITTING';
            app.GridLayout17 = uigridlayout(app.FITTINGPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout17);
            app.ModelfittingTab = uitab(app.TabGroup2);
            app.ModelfittingTab.Title = 'Model fitting';
            app.GridLayout29 = uigridlayout(app.ModelfittingTab);
            app.ModelnameLabel = uilabel(app.GridLayout29);
            app.ModelnameLabel.Text = 'Model name: - ';
            app.ModelVarianceC0Label = uilabel(app.GridLayout29);
            app.ModelVarianceC0Label.Text = 'Model Variance C(0): -';
            app.Correlationlength12Label_2 = uilabel(app.GridLayout29);
            app.Correlationlength12Label_2.Text = 'Correlation length ξ1/2: -';
            app.FittedSSELabel = uilabel(app.GridLayout29);
            app.FittedSSELabel.Text = 'Fitted SSE: - ';
            app.AnalyticalModelDropDownLabel = uilabel(app.GridLayout29);
            app.AnalyticalModelDropDownLabel.Text = 'Analytical Model';
            app.AnalyticalModelDropDown = uidropdown(app.GridLayout29);
            app.AnalyticalModelDropDown.Items = {'Exponential', 'Hirvonen', 'Gaussian', 'Forsberg Planar', 'Cauchy', 'Spherical', 'Tscherning-Rapp', '2nd-order Gauss-Markov', '3rd-order Gauss-Markov'};
            app.AnalyticalModelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AnalyticalModelDropDownOpening, true);
            app.AnalyticalModelDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalyticalModelDropDownValueChanged, true);
            app.AnalyticalModelDropDown.Value = 'Exponential';
            app.FitButton = uibutton(app.GridLayout29, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Icon = fullfile(pathToMLAPP, 'icons', 'plot.png');
            app.FitButton.Text = 'Fit';
            app.ClearDataButton = uibutton(app.GridLayout29, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.ClearDataButton.Text = 'Clear Data';
            app.ClearButton = uibutton(app.GridLayout29, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.ClearButton.Text = 'Clear ';
            app.ComparisonTab = uitab(app.TabGroup2);
            app.ComparisonTab.Title = 'Comparison';
            app.GridLayout19 = uigridlayout(app.ComparisonTab);
            app.UITable = uitable(app.GridLayout19);
            app.UITable.ColumnName = {'Model'; 'Variance C(0)'; 'Correlation Length'; 'Fitted SSE'};
            app.UITable.RowName = {};
            app.Image_2 = uiimage(app.GridLayout28);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Frame 2.png');
            app.POINTPREDICTIONTab = uitab(app.TabGroup);
            app.POINTPREDICTIONTab.Title = 'POINT PREDICTION';
            app.GridLayout30 = uigridlayout(app.POINTPREDICTIONTab);
            app.LSCSETTINGSPanel = uipanel(app.GridLayout30);
            app.LSCSETTINGSPanel.Title = 'LSC SETTINGS';
            app.GridLayout22_7 = uigridlayout(app.LSCSETTINGSPanel);
            app.PredictiontypeDropDownLabel = uilabel(app.GridLayout22_7);
            app.PredictiontypeDropDownLabel.Text = 'Prediction type';
            app.PredictiontypeDropDown = uidropdown(app.GridLayout22_7);
            app.PredictiontypeDropDown.Items = {'Gravity anomaly -> Gravity anomaly', 'Gravity anomaly -> Geoid height'};
            app.PredictiontypeDropDown.Value = 'Gravity anomaly -> Gravity anomaly';
            app.ModelLabel_3 = uilabel(app.GridLayout22_7);
            app.ModelLabel_3.Text = 'Model: ';
            app.DistancetypeLabel_2 = uilabel(app.GridLayout22_7);
            app.DistancetypeLabel_2.Text = 'Distance type:';
            app.ObsarvationnoiseEditFieldLabel = uilabel(app.GridLayout22_7);
            app.ObsarvationnoiseEditFieldLabel.Text = 'Obsarvation noise ';
            app.ObsarvationnoiseEditField = uieditfield(app.GridLayout22_7, 'numeric');
            app.ObsarvationnoiseEditField.Enable = 'off';
            app.ObsarvationnoiseEditField.Value = 0.5;
            app.EditdefaultstdCheckBox = uicheckbox(app.GridLayout22_7);
            app.EditdefaultstdCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditdefaultstdCheckBoxValueChanged, true);
            app.EditdefaultstdCheckBox.Text = 'Edit default std';
            app.SYNTHETICVALIDATIONPanel = uipanel(app.GridLayout30);
            app.SYNTHETICVALIDATIONPanel.Title = 'SYNTHETIC VALIDATION';
            app.GridLayout22_6 = uigridlayout(app.SYNTHETICVALIDATIONPanel);
            app.RunButton_5 = uibutton(app.GridLayout22_6, 'push');
            app.RunButton_5.ButtonPushedFcn = createCallbackFcn(app, @RunButton_5Pushed, true);
            app.RunButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'calc.png');
            app.RunButton_5.Text = 'Run';
            app.TestsplitEditField = uieditfield(app.GridLayout22_6, 'numeric');
            app.TestsplitEditField.Enable = 'off';
            app.TestsplitEditField.Value = 80;
            app.EditdefaultvalueCheckBox = uicheckbox(app.GridLayout22_6);
            app.EditdefaultvalueCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditdefaultvalueCheckBoxValueChanged, true);
            app.EditdefaultvalueCheckBox.Text = 'Edit default value';
            app.RandomSeedSpinner_2Label = uilabel(app.GridLayout22_6);
            app.RandomSeedSpinner_2Label.Text = 'Random Seed';
            app.RandomSeedSpinner_2 = uispinner(app.GridLayout22_6);
            app.RandomSeedSpinner_2.Value = 1;
            app.TestsplitLabel = uilabel(app.GridLayout22_6);
            app.TestsplitLabel.Text = 'Test split %';
            app.REALDATAPREDICTIONPanel = uipanel(app.GridLayout30);
            app.REALDATAPREDICTIONPanel.Title = 'REAL DATA PREDICTION';
            app.GridLayout31 = uigridlayout(app.REALDATAPREDICTIONPanel);
            app.TabGroup3 = uitabgroup(app.GridLayout31);
            app.PredictionFileTab = uitab(app.TabGroup3);
            app.PredictionFileTab.Title = 'Prediction File';
            app.GridLayout32 = uigridlayout(app.PredictionFileTab);
            app.PredictionfilenotloadedLabel = uilabel(app.GridLayout32);
            app.PredictionfilenotloadedLabel.Text = 'Prediction file: not loaded';
            app.FormatLabel = uilabel(app.GridLayout32);
            app.FormatLabel.Text = 'Format:';
            app.PredictionpointsLabel = uilabel(app.GridLayout32);
            app.PredictionpointsLabel.Text = 'Prediction points:';
            app.ImportPredictionFileButton = uibutton(app.GridLayout32, 'push');
            app.ImportPredictionFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPredictionFileButtonPushed, true);
            app.ImportPredictionFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'file-import.png');
            app.ImportPredictionFileButton.Text = 'Import Prediction File';
            app.RunButton_4 = uibutton(app.GridLayout32, 'push');
            app.RunButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunButton_4Pushed, true);
            app.RunButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'calc.png');
            app.RunButton_4.Text = 'Run';
            app.TrainFieldTab = uitab(app.TabGroup3);
            app.TrainFieldTab.Title = 'Train Field';
            app.GridLayout33 = uigridlayout(app.TrainFieldTab);
            app.TestsplitLabel_2 = uilabel(app.GridLayout33);
            app.TestsplitLabel_2.Text = 'Test split %';
            app.TestsplitEditField_2 = uieditfield(app.GridLayout33, 'numeric');
            app.TestsplitEditField_2.Enable = 'off';
            app.TestsplitEditField_2.Value = 80;
            app.EditdefaultvalueCheckBox_2 = uicheckbox(app.GridLayout33);
            app.EditdefaultvalueCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EditdefaultvalueCheckBox_2ValueChanged, true);
            app.EditdefaultvalueCheckBox_2.Text = 'Edit default value';
            app.RandomSeedSpinner_3Label = uilabel(app.GridLayout33);
            app.RandomSeedSpinner_3Label.Text = 'Random Seed';
            app.RandomSeedSpinner_3 = uispinner(app.GridLayout33);
            app.RandomSeedSpinner_3.Value = 1;
            app.RunButton_6 = uibutton(app.GridLayout33, 'push');
            app.RunButton_6.ButtonPushedFcn = createCallbackFcn(app, @RunButton_6Pushed, true);
            app.RunButton_6.Icon = fullfile(pathToMLAPP, 'icons', 'calc.png');
            app.RunButton_6.Text = 'Run';
            app.PlottingPanel_2 = uipanel(app.GridLayout30);
            app.PlottingPanel_2.Title = 'Plotting';
            app.GridLayout23 = uigridlayout(app.PlottingPanel_2);
            app.TabGroup3_2 = uitabgroup(app.GridLayout23);
            app.InputFieldTab = uitab(app.TabGroup3_2);
            app.InputFieldTab.Title = 'Input Field';
            app.GridLayout24 = uigridlayout(app.InputFieldTab);
            app.UIAxes3 = uiaxes(app.GridLayout24);
            title(app.UIAxes3, 'Iput field')
            xlabel(app.UIAxes3, 'Longitude (degrees)')
            ylabel(app.UIAxes3, 'Latitude (degrees)')
            zlabel(app.UIAxes3, 'Z')
            app.CreatePlotButton_2 = uibutton(app.GridLayout24, 'push');
            app.CreatePlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreatePlotButton_2Pushed, true);
            app.CreatePlotButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'graph.png');
            app.CreatePlotButton_2.Text = 'Create Plot';
            app.ExportPlotButton = uibutton(app.GridLayout24, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Icon = fullfile(pathToMLAPP, 'icons', 'file-export.png');
            app.ExportPlotButton.Text = 'Export Plot';
            app.ClearplotButton = uibutton(app.GridLayout24, 'push');
            app.ClearplotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearplotButton.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.ClearplotButton.Text = 'Clear plot';
            app.PredictionMapTab = uitab(app.TabGroup3_2);
            app.PredictionMapTab.Title = 'Prediction Map';
            app.GridLayout24_2 = uigridlayout(app.PredictionMapTab);
            app.UIAxes4 = uiaxes(app.GridLayout24_2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.CreatePlotButton_3 = uibutton(app.GridLayout24_2, 'push');
            app.CreatePlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @CreatePlotButton_3Pushed, true);
            app.CreatePlotButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'graph.png');
            app.CreatePlotButton_3.Text = 'Create Plot';
            app.ExportPlotButton_2 = uibutton(app.GridLayout24_2, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_2Pushed, true);
            app.ExportPlotButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'file-export.png');
            app.ExportPlotButton_2.Text = 'Export Plot';
            app.ClearplotButton_2 = uibutton(app.GridLayout24_2, 'push');
            app.ClearplotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButton_2Pushed, true);
            app.ClearplotButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.ClearplotButton_2.Text = 'Clear plot';
            app.ResidualMapTab = uitab(app.TabGroup3_2);
            app.ResidualMapTab.Title = 'Residual Map';
            app.GridLayout24_3 = uigridlayout(app.ResidualMapTab);
            app.UIAxes5 = uiaxes(app.GridLayout24_3);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.CreatePlotButton_4 = uibutton(app.GridLayout24_3, 'push');
            app.CreatePlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @CreatePlotButton_4Pushed, true);
            app.CreatePlotButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'graph.png');
            app.CreatePlotButton_4.Text = 'Create Plot';
            app.ExportPlotButton_3 = uibutton(app.GridLayout24_3, 'push');
            app.ExportPlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_3Pushed, true);
            app.ExportPlotButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'file-export.png');
            app.ExportPlotButton_3.Text = 'Export Plot';
            app.ClearplotButton_3 = uibutton(app.GridLayout24_3, 'push');
            app.ClearplotButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButton_3Pushed, true);
            app.ClearplotButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.ClearplotButton_3.Text = 'Clear plot';
            app.PredictionAccuracyTab = uitab(app.TabGroup3_2);
            app.PredictionAccuracyTab.Title = 'Prediction Accuracy';
            app.GridLayout24_7 = uigridlayout(app.PredictionAccuracyTab);
            app.UIAxes5_3 = uiaxes(app.GridLayout24_7);
            title(app.UIAxes5_3, 'Observed vs predicted')
            xlabel(app.UIAxes5_3, 'Observed \Delta g (mGal)')
            ylabel(app.UIAxes5_3, 'Predicted \Delta \hat{g} (mGal)')
            zlabel(app.UIAxes5_3, 'Z')
            app.CreatePlotButton_8 = uibutton(app.GridLayout24_7, 'push');
            app.CreatePlotButton_8.ButtonPushedFcn = createCallbackFcn(app, @CreatePlotButton_8Pushed, true);
            app.CreatePlotButton_8.Icon = fullfile(pathToMLAPP, 'icons', 'graph.png');
            app.CreatePlotButton_8.Text = 'Create Plot';
            app.ExportPlotButton_7 = uibutton(app.GridLayout24_7, 'push');
            app.ExportPlotButton_7.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_7Pushed, true);
            app.ExportPlotButton_7.Icon = fullfile(pathToMLAPP, 'icons', 'file-export.png');
            app.ExportPlotButton_7.Text = 'Export Plot';
            app.ClearplotButton_7 = uibutton(app.GridLayout24_7, 'push');
            app.ClearplotButton_7.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButton_7Pushed, true);
            app.ClearplotButton_7.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.ClearplotButton_7.Text = 'Clear plot';
            app.ErrorCovarianceMatrixTab = uitab(app.TabGroup3_2);
            app.ErrorCovarianceMatrixTab.Title = 'Error Covariance Matrix';
            app.GridLayout24_6 = uigridlayout(app.ErrorCovarianceMatrixTab);
            app.UIAxes5_2 = uiaxes(app.GridLayout24_6);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.CreatePlotButton_7 = uibutton(app.GridLayout24_6, 'push');
            app.CreatePlotButton_7.ButtonPushedFcn = createCallbackFcn(app, @CreatePlotButton_7Pushed, true);
            app.CreatePlotButton_7.Icon = fullfile(pathToMLAPP, 'icons', 'graph.png');
            app.CreatePlotButton_7.Text = 'Create Plot';
            app.ExportPlotButton_6 = uibutton(app.GridLayout24_6, 'push');
            app.ExportPlotButton_6.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_6Pushed, true);
            app.ExportPlotButton_6.Icon = fullfile(pathToMLAPP, 'icons', 'file-export.png');
            app.ExportPlotButton_6.Text = 'Export Plot';
            app.ClearplotButton_6 = uibutton(app.GridLayout24_6, 'push');
            app.ClearplotButton_6.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButton_6Pushed, true);
            app.ClearplotButton_6.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.ClearplotButton_6.Text = 'Clear plot';
            app.ResidualHistogramTab = uitab(app.TabGroup3_2);
            app.ResidualHistogramTab.Title = 'Residual Histogram';
            app.GridLayout24_4 = uigridlayout(app.ResidualHistogramTab);
            app.UIAxes6 = uiaxes(app.GridLayout24_4);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.CreatePlotButton_5 = uibutton(app.GridLayout24_4, 'push');
            app.CreatePlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @CreatePlotButton_5Pushed, true);
            app.CreatePlotButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'graph.png');
            app.CreatePlotButton_5.Text = 'Create Plot';
            app.ExportPlotButton_4 = uibutton(app.GridLayout24_4, 'push');
            app.ExportPlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_4Pushed, true);
            app.ExportPlotButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'file-export.png');
            app.ExportPlotButton_4.Text = 'Export Plot';
            app.ClearplotButton_4 = uibutton(app.GridLayout24_4, 'push');
            app.ClearplotButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButton_4Pushed, true);
            app.ClearplotButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.ClearplotButton_4.Text = 'Clear plot';
            app.ResidualGeoidFieldTab = uitab(app.TabGroup3_2);
            app.ResidualGeoidFieldTab.Title = 'Residual Geoid Field';
            app.GridLayout24_5 = uigridlayout(app.ResidualGeoidFieldTab);
            app.UIAxes7 = uiaxes(app.GridLayout24_5);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.CreatePlotButton_6 = uibutton(app.GridLayout24_5, 'push');
            app.CreatePlotButton_6.ButtonPushedFcn = createCallbackFcn(app, @CreatePlotButton_6Pushed, true);
            app.CreatePlotButton_6.Icon = fullfile(pathToMLAPP, 'icons', 'graph.png');
            app.CreatePlotButton_6.Text = 'Create Plot';
            app.ExportPlotButton_5 = uibutton(app.GridLayout24_5, 'push');
            app.ExportPlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_5Pushed, true);
            app.ExportPlotButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'file-export.png');
            app.ExportPlotButton_5.Text = 'Export Plot';
            app.ClearplotButton_5 = uibutton(app.GridLayout24_5, 'push');
            app.ClearplotButton_5.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButton_5Pushed, true);
            app.ClearplotButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.ClearplotButton_5.Text = 'Clear plot';
            app.RESULTSSTATUSPanel = uipanel(app.GridLayout30);
            app.RESULTSSTATUSPanel.Title = 'RESULTS/STATUS';
            app.GridLayout22_8 = uigridlayout(app.RESULTSSTATUSPanel);
            app.ResetButton_4 = uibutton(app.GridLayout22_8, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'rotate.png');
            app.ResetButton_4.Text = 'Reset';
            app.StatusidleLabel = uilabel(app.GridLayout22_8);
            app.StatusidleLabel.Text = 'Status: idle';
            app.Lamp_2 = uilamp(app.GridLayout22_8);
            app.ObservationsusedLabel = uilabel(app.GridLayout22_8);
            app.ObservationsusedLabel.Text = 'Observations used: -';
            app.ModelLabel_4 = uilabel(app.GridLayout22_8);
            app.ModelLabel_4.Text = 'Model: -';
            app.TargetquantityLabel = uilabel(app.GridLayout22_8);
            app.TargetquantityLabel.Text = 'Target quantity: -';
            app.ComputationtimeLabel_2 = uilabel(app.GridLayout22_8);
            app.ComputationtimeLabel_2.Text = 'Computation time: -';
            app.RMSELabel = uilabel(app.GridLayout22_8);
            app.RMSELabel.Enable = 'off';
            app.RMSELabel.Text = 'RMSE: -';
            app.BiasLabel = uilabel(app.GridLayout22_8);
            app.BiasLabel.Enable = 'off';
            app.BiasLabel.Text = 'Bias: - ';
            app.StdresidualLabel = uilabel(app.GridLayout22_8);
            app.StdresidualLabel.Enable = 'off';
            app.StdresidualLabel.Text = 'Std residual: - ';
            app.MeanpredictionstdLabel = uilabel(app.GridLayout22_8);
            app.MeanpredictionstdLabel.Enable = 'off';
            app.MeanpredictionstdLabel.Text = 'Mean prediction std: - ';
            app.MinMaxpredictionstdLabel = uilabel(app.GridLayout22_8);
            app.MinMaxpredictionstdLabel.Enable = 'off';
            app.MinMaxpredictionstdLabel.Text = 'Min/Max prediction std: - ';
            app.ModeLabel = uilabel(app.GridLayout22_8);
            app.ModeLabel.Text = 'Mode: -';
            app.StageLabel = uilabel(app.GridLayout22_8);
            app.StageLabel.Text = 'Stage: -';
            app.DatasplitpointsLabel = uilabel(app.GridLayout22_8);
            app.DatasplitpointsLabel.Text = 'Data split / points: -';
            app.MatrixsizeLabel = uilabel(app.GridLayout22_8);
            app.MatrixsizeLabel.Text = 'Matrix size: -';
            app.NumericalstatusLabel = uilabel(app.GridLayout22_8);
            app.NumericalstatusLabel.Text = 'Numerical status: -';
            app.Image_3 = uiimage(app.GridLayout30);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Frame 2.png');
            app.ComputationtimeLabel = uilabel(app.UIFigure);
            app.ComputationtimeLabel.Text = 'Computation time: -';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TsiT_CovApp_vol3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end